classdef MORBIT_SingleRun_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WeatherTab                      matlab.ui.container.Tab
        WaveDirectionPanel              matlab.ui.container.Panel
        WindDirectionPanel              matlab.ui.container.Panel
        WeatherDataEditField            matlab.ui.control.EditField
        WeatherDataEditFieldLabel       matlab.ui.control.Label
        WeatherData                     matlab.ui.control.Button
        StartDate                       matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        WindSpeedPlot                   matlab.ui.control.UIAxes
        WaveHeightPlot                  matlab.ui.control.UIAxes
        LocationsTab                    matlab.ui.container.Tab
        MapPanel                        matlab.ui.container.Panel
        ArrayCoordinatesEditField       matlab.ui.control.EditField
        ArrayCoordinatesEditFieldLabel  matlab.ui.control.Label
        ArrayCoordinates                matlab.ui.control.Button
        MooringCooridor                 matlab.ui.control.Button
        MooringLongitude                matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel_2       matlab.ui.control.Label
        MooringLatitude                 matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel_2        matlab.ui.control.Label
        MooringPortDropDown             matlab.ui.control.DropDown
        TurbinePortLabel_2              matlab.ui.control.Label
        MooringCooridorEditField        matlab.ui.control.EditField
        MooringCooridorEditFieldLabel   matlab.ui.control.Label
        SamePorts                       matlab.ui.control.CheckBox
        TurbineCooridorEditField        matlab.ui.control.EditField
        TurbineCooridorEditFieldLabel   matlab.ui.control.Label
        TurbineCooridor                 matlab.ui.control.Button
        TurbineLongitude                matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        TurbineLatitude                 matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        TurbinePortDropDown             matlab.ui.control.DropDown
        TurbinePortLabel                matlab.ui.control.Label
        Vessels                         matlab.ui.container.Tab
        TowTowSpeed                     matlab.ui.control.NumericEditField
        SpeedkmhEditField_2Label_2      matlab.ui.control.Label
        AHTSTransitWind                 matlab.ui.control.NumericEditField
        TransitWindSpeedLimitmsEditField_2Label  matlab.ui.control.Label
        AHTSTransitWave                 matlab.ui.control.NumericEditField
        TransitWaveHeightLimitmEditField_2Label  matlab.ui.control.Label
        AHTSMooringWind                 matlab.ui.control.NumericEditField
        MooringInstallWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        AHTSMooringWave                 matlab.ui.control.NumericEditField
        MooringInstallWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        AHTSAnchorWind                  matlab.ui.control.NumericEditField
        AnchorInstallWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        AHTSAnchorWave                  matlab.ui.control.NumericEditField
        AnchorInstallWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        AHTSPreInstallWind              matlab.ui.control.NumericEditField
        PreInstallWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        AHTSPreInstallWave              matlab.ui.control.NumericEditField
        PreInstallWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        AHTSMob                         matlab.ui.control.NumericEditField
        MobilizeTimehrEditField_2Label  matlab.ui.control.Label
        AHTSRate                        matlab.ui.control.NumericEditField
        HourlyRateEditField_2Label      matlab.ui.control.Label
        AHTSSpeed                       matlab.ui.control.NumericEditField
        SpeedkmhEditFieldLabel          matlab.ui.control.Label
        AHTSVesselName                  matlab.ui.control.EditField
        AHTSVesselNameEditFieldLabel    matlab.ui.control.Label
        TowTowWind                      matlab.ui.control.NumericEditField
        TowingWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        TowTowWave                      matlab.ui.control.NumericEditField
        TowingWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        TowTransitWind                  matlab.ui.control.NumericEditField
        TransitWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        TowTransitWave                  matlab.ui.control.NumericEditField
        TransitWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        TowMob                          matlab.ui.control.NumericEditField
        MobilizeTimehrEditFieldLabel    matlab.ui.control.Label
        TowRate                         matlab.ui.control.NumericEditField
        HourlyRateLabel                 matlab.ui.control.Label
        TowTransitSpeed                 matlab.ui.control.NumericEditField
        TransitSpeedkmhLabel            matlab.ui.control.Label
        TowVesselName                   matlab.ui.control.EditField
        TowVesselNameEditFieldLabel     matlab.ui.control.Label
        Project                         matlab.ui.container.Tab
        ResultsTable                    matlab.ui.control.Table
        NumTurb                         matlab.ui.control.NumericEditField
        WetStorageLabel_2               matlab.ui.control.Label
        WetStorage                      matlab.ui.control.NumericEditField
        WetStorageLabel                 matlab.ui.control.Label
        TurbineAssemblyTime             matlab.ui.control.NumericEditField
        TurbineAssemblyTimehrLabel      matlab.ui.control.Label
        NumLines                        matlab.ui.control.NumericEditField
        NumberofLinesPerTurbineEditFieldLabel  matlab.ui.control.Label
        ExportFileName                  matlab.ui.control.EditField
        ExportFileNamematEditFieldLabel  matlab.ui.control.Label
        RunSimulation                   matlab.ui.control.Button
        ResultsSummary                  matlab.ui.control.TextArea
        ResultsSummaryLabel             matlab.ui.control.Label
        ResultsPlot                     matlab.ui.control.UIAxes
        PostprocessingMap               matlab.ui.container.Tab
        CreateMap                       matlab.ui.control.Button
        MapType                         matlab.ui.control.DropDown
        ChartTypeDropDownLabel          matlab.ui.control.Label
        ResultFileNameMap               matlab.ui.control.EditField
        ExportFileNamematEditField_2Label  matlab.ui.control.Label
        ResultMapPanel                  matlab.ui.container.Panel
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
       WindRose
       WaveRose
       MapAxesObject
       ResultMap
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WindRose = polaraxes(app.WindDirectionPanel);
            app.WaveRose = polaraxes(app.WaveDirectionPanel);
            app.MapAxesObject = geoaxes(app.MapPanel);
            geobasemap (app.MapAxesObject, 'topographic')
            app.ResultMap = geoaxes(app.ResultMapPanel);
            geobasemap (app.ResultMap, 'topographic')
        end

        % Callback function
        function TurbinePortDropDownOpening(app, event)

        end

        % Value changed function: TurbinePortDropDown
        function TurbinePortDropDownValueChanged(app, event)
            % Update Latitude and Longitude
            if strcmp(app.TurbinePortDropDown.Value,"humboldt")
                app.TurbineLatitude.Value = 40.807565;
                app.TurbineLongitude.Value = -124.162268;
            elseif strcmp(app.TurbinePortDropDown.Value,"sanfran")
                app.TurbineLatitude.Value = 37.794402;
                app.TurbineLongitude.Value = -122.391938;
            elseif strcmp(app.TurbinePortDropDown.Value,"longbeach")
                app.TurbineLatitude.Value = 33.739737;
                app.TurbineLongitude.Value = -118.205367;
            elseif strcmp(app.TurbinePortDropDown.Value,"searsport")
                app.TurbineLatitude.Value = 44.451783;
                app.TurbineLongitude.Value = -68.902169;
            elseif strcmp(app.TurbinePortDropDown.Value,"lanouvelle")
                app.TurbineLatitude.Value = 43.01667;
                app.TurbineLongitude.Value = 3.05;
             elseif strcmp(app.TurbinePortDropDown.Value,"nigg")
                app.TurbineLatitude.Value = 27.6943398;
                app.TurbineLongitude.Value = -4.03249741;
            elseif strcmp(app.TurbinePortDropDown.Value,"montrose")
                app.TurbineLatitude.Value = 56.7;
                app.TurbineLongitude.Value = -2.4667;
            elseif strcmp(app.TurbinePortDropDown.Value,"talbot")
                app.TurbineLatitude.Value = 51.5947;
                app.TurbineLongitude.Value = -3.7841;
            end
            if app.SamePorts.Value == 1
                app.MooringPortDropDown.Value  = app.TurbinePortDropDown.Value;
                app.MooringLatitude.Value = app.TurbineLatitude.Value;
                app.MooringLongitude.Value = app.TurbineLongitude.Value;
                app.MooringCooridor = app.TurbineCooridor;
            end
        end

        % Value changed function: SamePorts
        function SamePortsValueChanged(app, event)
            value = app.SamePorts.Value;
            if value == 1
                app.MooringPortDropDown.Value  = app.TurbinePortDropDown.Value;
                app.MooringLatitude.Value = app.TurbineLatitude.Value;
                app.MooringLongitude.Value = app.TurbineLongitude.Value;
                app.MooringCooridor = app.TurbineCooridor;
            end
 
        end

        % Value changed function: MooringPortDropDown
        function MooringPortDropDownValueChanged(app, event)
            % Update Latitude and Longitude
            if strcmp(app.MooringPortDropDown.Value,"humboldt")
                app.MooringLatitude.Value = 40.807565;
                app.MooringLongitude.Value = -124.162268;
            elseif strcmp(app.MooringPortDropDown.Value,"sanfran")
                app.MooringLatitude.Value = 37.794402;
                app.MooringLongitude.Value = -122.391938;
            elseif strcmp(app.MooringPortDropDown.Value,"longbeach")
                app.MooringLatitude.Value = 33.739737;
                app.MooringLongitude.Value = -118.205367;
            elseif strcmp(app.MooringPortDropDown.Value,"searsport")
                app.MooringLatitude.Value = 44.451783;
                app.MooringLongitude.Value = -68.902169;
            elseif strcmp(app.MooringPortDropDown.Value,"lanouvelle")
                app.MooringLatitude.Value = 43.01667;
                app.MooringLongitude.Value = 3.05;
             elseif strcmp(app.MooringPortDropDown.Value,"nigg")
                app.MooringLatitude.Value = 27.6943398;
                app.MooringLongitude.Value = -4.03249741;
            elseif strcmp(app.MooringPortDropDown.Value,"montrose")
                app.MooringLatitude.Value = 56.7;
                app.MooringLongitude.Value = -2.4667;
            elseif strcmp(app.MooringPortDropDown.Value,"talbot")
                app.MooringLatitude.Value = 51.5947;
                app.MooringLongitude.Value = -3.7841;
            else
                warning('WARNING: Unknown Port')
            end
        end

        % Button pushed function: WeatherData
        function WeatherDataButtonPushed(app, event)
            % This is weather data from the ERA5 dataset from the CDS API.
            % the wind speed from the original data was scaled from 100m to 10m winds using a scaling factor of 0.14.
            [weatherdatafile, weatherdatapath] = uigetfile('*.mat'); %open a mat file
            app.WeatherDataEditField.Value = weatherdatafile;
            % Load
            try
                load(app.WeatherDataEditField.Value, 'wave', 'wind');
            catch
                warning('ERROR: Weather data not loaded. Ensure file type is *.mat and contains wind and weather structs.')
            end
            % Update Plots
            try
                plot(app.WaveHeightPlot, wave.t, wave.Hstotal);
                plot(app.WindSpeedPlot,wind.t,wind.Spd);
                polarhistogram(app.WindRose,deg2rad(wind.Dir),deg2rad(0:10:360))
                polarhistogram(app.WaveRose,deg2rad(wave.DmTotal),deg2rad(0:10:360))
            catch
                warning('ERROR: Weather data not in correct format. Check input file.')
            end
        end

        % Button pushed function: TurbineCooridor
        function TurbineCooridorButtonPushed(app, event)
            % define the corridor coordinates and max transit speed.
            % lat, lon, speed in km/h
            try
                [turbinecooridorfile,turbinecooridorpath] = uigetfile('*.csv'); %open a csv file
                app.TurbineCooridorEditField.Value = turbinecooridorfile;
            catch
                warning('ERROR: Corridor data not loaded. Ensure file type is *.csv.')
            end
            % Load data
            TurbineMarshalPort = [app.TurbineLatitude.Value, app.TurbineLongitude.Value, 2740];
            TowingCorridor = readmatrix(app.TurbineCooridorEditField.Value);
            % Plot on map
            hold(app.MapAxesObject,'on')
            geoplot(app.MapAxesObject,TowingCorridor(:,1),TowingCorridor(:,2),'-g');
            geoscatter(app.MapAxesObject,TurbineMarshalPort(1),TurbineMarshalPort(2),'g')
            hold(app.MapAxesObject,'off')
        end

        % Button pushed function: MooringCooridor
        function MooringCooridorPushed(app, event)
            try
                [mooringcooridorfile,mooringcooridorpath] = uigetfile('*.csv'); %open a csv file
                app.MooringCooridorEditField.Value = mooringcooridorfile;
            catch
                warning('ERROR: Corridor data not loaded. Ensure file type is *.csv.')
            end
            % Load data
            MooringMarshalPort = [app.MooringLatitude.Value, app.MooringLongitude.Value, 0];
            MooringCorridor = readmatrix(app.MooringCooridorEditField.Value);
            % Plot on map
            hold(app.MapAxesObject,'on')
            geoplot(app.MapAxesObject,MooringCorridor(:,1),MooringCorridor(:,2),'-b');
            geoscatter(app.MapAxesObject,MooringMarshalPort(1),MooringMarshalPort(2),'b')
            hold(app.MapAxesObject,'off')
        end

        % Button pushed function: ArrayCoordinates
        function ArrayCoordinatesButtonPushed(app, event)
            try
                [arrayfile,arraypath] = uigetfile('*.csv');  %open a csv file
                app.ArrayCoordinatesEditField.Value = arrayfile;
            catch
                warning('ERROR: Corridor data not loaded. Ensure file type is *.csv.')
            end   
            % Load data
            arr = readmatrix(app.ArrayCoordinatesEditField.Value);
            % Plot on map
            hold(app.MapAxesObject,'on')
            geoscatter(app.MapAxesObject,arr(:,6),arr(:,7),"k");
            hold(app.MapAxesObject,'off')
        end

        % Button pushed function: RunSimulation
        function RunSimulationButtonPushed(app, event)

            % Set the start time of the simulation.
            startTime = app.StartDate.Value;
            
            % Load input files
            load(app.WeatherDataEditField.Value, 'wave', 'wind');
            TurbineMarshalPort = [app.TurbineLatitude.Value, app.TurbineLongitude.Value, 2740];
            TowingCorridor = readmatrix(app.TurbineCooridorEditField.Value);
            MooringMarshalPort = [app.MooringLatitude.Value, app.MooringLongitude.Value, 0];
            MooringCorridor = readmatrix(app.MooringCooridorEditField.Value);
            arr = readmatrix(app.ArrayCoordinatesEditField.Value);

            % Sample the weather data between certain dates for processing speed.
            % providing the entire weather data set, if it is long, can slow down the simulation a lot.
            % The "+ years(3)" may need to be modified depending on your WEA and the number of turbines.
            % This number is currently based on a 50 turbine array in California.
            % If there is a an index error in the weather agent, this number should be increased.
            idx = wave.t > startTime & wave.t < startTime + years(10);
            wnames = fieldnames(wave);
            for w = 1:length(wnames)
                wave.(wnames{w}) = wave.(wnames{w})(idx);
            end
            wnames = fieldnames(wind);
            for w = 1:length(wnames)
                wind.(wnames{w}) = wind.(wnames{w})(idx);
            end

            %% WEA and Turbine characteristics
            % Load a csv from a shp file with lat, lon, and depth

            numTurbines = length(arr); % The number of turbines in the array
            numLines    = app.NumLines.Value;           % number of lines per turbine
            turbAssemblyTime = app.TurbineAssemblyTime.Value;    % hours


            %% Create the simulation structure
            % initialize the simulation step. This structure is passed between agents at each step of the simulation and is the way
            % that agents communicate with one another, as well as where information about each time step is extracted.
            simStep = struct();
            simStep.time = 0;
            simStep.proj = wgs84Ellipsoid("km");

            % simStep.arry defines all of the simulation-important information about the WEA.
            % array is lat, lon, status, id, completion time, depth
            % status can be as follows:
            % 0 - Nothing installed
            % 1 - Mooring Install In Progress
            % 2 - Moorings installed
            % 3 - Turbine installed
            simStep.array = [arr(:, [6,7]),...
                zeros(size((1:numTurbines)')),...
                (1:numTurbines)',...
                zeros(size((1:numTurbines)')), abs(arr(:, 3))];
            simStep.mooringsIn = [simStep.array(:, 4), zeros(length(simStep.array), 6)];

            % instantiating communications throughout the simulation.
            simStep.loadTurbine   = false; % Is the tow vessel loading a turbine
            simStep.loadMoorings  = false; % Is the AHTS loading mooring lines
            simStep.nMoorings     = 0; % number of moorings provided to ahts by the mooring port
            simStep.nTurbines     = 0; % number of turbines in wet storage
            simStep.numMoorings   = numLines; % provides number of moorings to the sim object
            simStep.AHTSonSite    = false; % tells the towing vessel if the AHTS is on site for hook up
            simStep.towInfo       = [inf inf]; % provides towing information to the AHTS
            simStep.towWait       = true; % tells the vessel that the AHTS is too far away for a tow out to start
            simStep.timeToTurbine = 0; % provide information about turbine assembly so the AHTS can leave for periods of time
            simStep.forecast      = []; % provides an empty array for the forecast, overwritten if there is a weather module


            %% Vessel Corridor Info
            % To reduce the number of calculations within the simulation, the corridor travel time is calculated ahead and provided
            % to vessels in the following structure.
            t = 0;
            for c = 2:length(MooringCorridor)
                t = t + distance(MooringCorridor(c-1,1), MooringCorridor(c-1,2), ...
                    MooringCorridor(c,1), MooringCorridor(c,2), simStep.proj)/MooringCorridor(c,3);
            end
            mooringCorridorInfo = struct('rallyPoint', MooringCorridor(end, 1:2), 'time', round(t));

            t = 0;
            for c = 2:length(TowingCorridor)
                t = t + distance(TowingCorridor(c-1,1), TowingCorridor(c-1,2), ...
                    TowingCorridor(c,1), TowingCorridor(c,2), simStep.proj)/TowingCorridor(c,3);
            end
            towingCorridorInfo = struct('rallyPoint', TowingCorridor(end, 1:2), 'time', round(t));


            %% Agents
            % Initialize the individual classes for each agent and provide them with necessary information for them to be spun up.
            % Each variable provided to an agent is described in the architecture document.
            agents = struct( ...
                ... Weather(time, windSpd, windDir, waveHs, waveTp, waveDir) 
                'Weather', Weather(wind.t, wind.Spd, wind.Dir, wave.Hstotal, wave.TpTotal, wave.DmTotal),...
                ... Towing(name, speed, speedLookup, hourlyRate, mobilizeTime, startLocation, corridor, transitLim, towingLim)
                'TowVessel', Towing(app.TowVesselName.Value, app.TowTransitSpeed.Value, [app.TowTransitSpeed.Value app.TowTowSpeed.Value], app.TowRate.Value, app.TowMob.Value, TurbineMarshalPort(1:2), towingCorridorInfo, [app.TowTransitWave.Value; app.TowTransitWind.Value], [app.TowTowWave.Value; app.TowTowWind.Value]), ...
                ... AHTS(name, speed, speedLookup, hourlyRate, mobilizeTime, startLocation, corridor, transitLim, preinstallLim, anchorLim, mooringLim)
                'AHTS', AHTS(app.AHTSVesselName.Value, app.AHTSSpeed.Value, [app.AHTSSpeed.Value app.AHTSSpeed.Value], app.AHTSRate.Value, app.AHTSMob.Value, MooringMarshalPort(1:2), mooringCorridorInfo, [app.AHTSTransitWave.Value; app.AHTSTransitWind.Value], [app.AHTSPreInstallWave.Value; app.AHTSPreInstallWind.Value], [app.AHTSAnchorWave.Value; app.AHTSAnchorWind.Value], [app.AHTSMooringWave.Value; app.AHTSMooringWind.Value]), ...
                ... MooringPort(name, lat, lon, hourlyRate)
                'MooringPort', MooringPort("Mooring Port", MooringMarshalPort(1), MooringMarshalPort(2), MooringMarshalPort(3)), ...
                ... TurbinePort(name, lat, lon, hourlyRate, assemblyTime, maxTurbines)
                'TurbinePort', TurbinePort("Turbine Port", TurbineMarshalPort(1), TurbineMarshalPort(2), TurbineMarshalPort(3), turbAssemblyTime, numTurbines));

            % Set some data in the ports
            agents.MooringPort = agents.MooringPort.addToStorage(numLines*numTurbines);
            agents.TurbinePort = agents.TurbinePort.SetMaxStorage(app.WetStorage.Value);
            agents.TurbinePort = agents.TurbinePort.InitStorage(app.NumTurb.Value);


            %% Run the simulation
            fnames = fieldnames(agents);
            done = false(size(fnames));
            iterMax = 1e6;
            iter = 0;
            weatherCounts = zeros(size(fnames));

            % iter should never approach iterMax, and in fact typically there will be an error in the weather module before 1e6
            % steps occur.
            while ~all(done) && iter <= iterMax
                % Step forward in time.
                simStep.time = startTime + hours(iter);

            % call each agent.
                for arr = 1:length(fnames)
                    [agents.(fnames{arr}), simStep, out] = agents.(fnames{arr}).step(simStep);
                    disp(out)

                    % keep track of weather delays.
                    if contains(out, 'delayed')
                        weatherCounts(arr) = weatherCounts(arr) + 1;
                    end

                    % keep track of which agents have finished operations.
                    if ~done(arr) && any(agents.(fnames{arr}).status ==  ["Finished", "Weather"])
                        done(arr) = true;
                    end
                end

                % Bookkeeping
                iter = iter + 1;
                if iter == 1
                    simSave(iter) = struct('time', simStep.time, 'proj', simStep.proj, 'arrayConfig', simStep.array(:, [1 2 4]), 'arrayStatus', simStep.array(:,[4 3 5]), 'MooringStatus', simStep.mooringsIn(:,2:end), 'transitCorridor', MooringCorridor);
                else
                    simSave(iter).time = simStep.time;
                    simSave(iter).arrayStatus = simStep.array(:, [4 3 5]);
                    simSave(iter).MooringStatus = simStep.mooringsIn(:,2:end);
                end
                for f = 1:length(fnames)
                    if agents.(fnames{f}).name == "Weather"
                        continue;
                    end
                    simSave(iter).(fnames{f}) = agents.(fnames{f});
                end
            end

            % Convert to a table (for some reason this is more matlab memory efficient) and save
            simSave = struct2table(simSave);
            save([app.ExportFileName.Value,'.mat'], "simSave")

            % Display total cost and time in Results Summary
            for arr = 1:length(fnames)
                if contains(fnames{arr}, 'Weather')
                    continue;
                end
                c = c + agents.(fnames{arr}).cost;
            end
            text1 = sprintf('Total cost: $%0.4fM\n', c/1000000);
            text2 = sprintf('Total time: %0.4f days\n', days(simStep.time - startTime));
            app.ResultsSummary.Value = {text1;text2};
                
            % Update results plot
            l = length(simSave.time);
            timeFromStart = 1:l;
            for i = 1:l
                statusi = simSave.arrayStatus{i,1}(:,2);
                mooringsInProgress(i) = sum(statusi==1);
                turbinesComplete(i) = sum(statusi==3);
                mooringsComplete(i) = turbinesComplete(i) + sum(statusi==2);
                mooringStorage(i,1) = simSave.MooringPort(i,1).storage/6;
                turbineStorage(i,1) = simSave.TurbinePort(i,1).storage;
            end
            daysFromStart = timeFromStart./24;
            plot(app.ResultsPlot, daysFromStart, mooringsInProgress)
            hold (app.ResultsPlot, 'on') 
            plot(app.ResultsPlot, daysFromStart, mooringsComplete)
            plot(app.ResultsPlot, daysFromStart, turbinesComplete)
            plot(app.ResultsPlot, daysFromStart, mooringStorage)
            plot(app.ResultsPlot, daysFromStart, turbineStorage)
            legend(app.ResultsPlot, 'Moorings in Progress', 'Moorings Complete', 'Turbines Installed','Moorings in Storage', 'Turbines in Storage')
            hold (app.ResultsPlot, 'off') 
            
            % Update Results Table
            % Results = [WeatherDelays(days) WorkingTime(days) Costs(M$)]
            TowDelay = round(weatherCounts(2)/24,1); % [days] Weather delay
            TowWorking = round(days(agents.TowVessel.time - startTime),1); % [days] Working time
            TowCost = agents.TowVessel.cost/1000000; % [M$]
            AHTSDelay = round(weatherCounts(3)/24,1); % [days] Weather delay
            AHTSWorking = round(days(agents.AHTS.time - startTime),1); % [days] Working time
            AHTSCost = agents.AHTS.cost/1000000; % [M$]
            MooringPortDelay = round(weatherCounts(4)/24,1); % [days] Weather delay
            MooringPortWorking = round(days(agents.MooringPort.time - startTime),1); % [days] Working time
            MooringPortCost = agents.MooringPort.cost/1000000; % [M$]
            TurbinePortDelay = round(weatherCounts(3)/24,1); % [days] Weather delay
            TurbinePortWorking = round(days(agents.TurbinePort.time - startTime),1); % [days] Working time
            TurbinePortCost = agents.TurbinePort.cost/1000000; % [M$]
            app.ResultsTable.ColumnName = {'Tow Vessel', 'AHTS', 'Mooring Port', 'TurbinePort'};
            app.ResultsTable.RowName = {'Weather Delay (days)','Working Time (days)', 'Cost (M$)'};
            app.ResultsTable.Data = table([TowDelay;TowWorking;TowCost], [AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
          
        end

        % Value changed function: StartDate
        function StartDateValueChanged(app, event)

        end

        % Value changed function: AHTSTransitWind
        function AHTSTransitWindValueChanged(app, event)

        end

        % Value changed function: AHTSTransitWave
        function AHTSTransitWaveValueChanged(app, event)

        end

        % Value changed function: AHTSMooringWind
        function AHTSMooringWindValueChanged(app, event)

        end

        % Value changed function: AHTSMooringWave
        function AHTSMooringWaveValueChanged(app, event)

        end

        % Value changed function: AHTSAnchorWind
        function AHTSAnchorWindValueChanged(app, event)

        end

        % Value changed function: AHTSAnchorWave
        function AHTSAnchorWaveValueChanged(app, event)

        end

        % Value changed function: AHTSPreInstallWind
        function AHTSPreInstallWindValueChanged(app, event)

        end

        % Value changed function: AHTSPreInstallWave
        function AHTSPreInstallWaveValueChanged(app, event)
 
        end

        % Value changed function: AHTSMob
        function AHTSMobValueChanged(app, event)

        end

        % Value changed function: AHTSRate
        function AHTSRateValueChanged(app, event)

        end

        % Value changed function: AHTSSpeed
        function AHTSSpeedValueChanged(app, event)

        end

        % Value changed function: AHTSVesselName
        function AHTSVesselNameValueChanged(app, event)

        end

        % Value changed function: TowTowWind
        function TowTowWindValueChanged(app, event)

        end

        % Value changed function: TowTowWave
        function TowTowWaveValueChanged(app, event)

        end

        % Value changed function: TowTransitWind
        function TowTransitWindValueChanged(app, event)

        end

        % Value changed function: TowTransitWave
        function TowTransitWaveValueChanged(app, event)

        end

        % Value changed function: TowMob
        function TowMobValueChanged(app, event)
 
        end

        % Value changed function: TowRate
        function TowRateValueChanged(app, event)

        end

        % Value changed function: TowTransitSpeed
        function TowTransitSpeedValueChanged(app, event)

        end

        % Value changed function: TowVesselName
        function TowVesselNameValueChanged(app, event)

        end

        % Value changed function: ExportFileName
        function ExportFileNameValueChanged(app, event)
            app.ResultFileNameMap.Value = app.ExportFileName.Value;

        end

        % Value changed function: MapType
        function MapTypeValueChanged(app, event)
        
        end

        % Value changed function: ResultFileNameMap
        function ResultFileNameMapValueChanged(app, event)

        end

        % Button pushed function: CreateMap
        function CreateMapButtonPushed(app, event)
            load(app.ResultFileNameMap.Value);
            cmap = parula(256);
            [l a]= size(simSave);
            time = 1:l;
            if strcmp(app.MapType.Value,"AHTSMap")
                disp('AHTS')
                % Plot Port
                MorPortLat = simSave.MooringPort.lat;
                MorPortLon = simSave.MooringPort.lon;
                geoscatter(app.ResultMap,MorPortLat,MorPortLon,'filled','r');
                disp('Port')
                % Plot Array
                arrayLat = simSave.arrayConfig{1,1}(:,1);
                arrayLon = simSave.arrayConfig{1,1}(:,2);
                geoscatter(app.ResultMap,arrayLat,arrayLon, 'filled','k');
                disp('Array')
                % Plot AHTS Path
                AHTSLat(1) = simSave.MooringPort(1).lat;
                AHTSLon(1) = simSave.MooringPort(1).lon;
                color_indices(1,1) = 1;
                for i = 2:l
                    AHTSStatus(i,1) = simSave.AHTS(i).status;
                    AHTSLat(i,1) = simSave.AHTS(i).lat;
                    AHTSLon(i,1) = simSave.AHTS(i).lon;
                    color_indices(i,1) = round((time(i)-1)/(l-1)*(size(cmap,1)-1))+1;
                    line_color(i,:) = cmap(color_indices(i),:);
                    geoplot(app.ResultMap,[AHTSLat(i-1) AHTSLat(i)], [AHTSLon(i-1) AHTSLon(i)],'Color',line_color(i,:));
                    disp(i)
                end
            elseif strcmp(app.MapType.Value,"TowMap")
                % Plot Port
                portLat = simSave.TurbinePort.lat;
                portLon = simSave.TurbinePort.lon;
                geoscatter(app.ResultMap,portLat,portLon,'filled','r');

                % Plot Array
                arrayLat = simSave.arrayConfig{1,1}(:,1);
                arrayLon = simSave.arrayConfig{1,1}(:,2);
                geoscatter(app.ResultMap,arrayLat,arrayLon, 'filled','k');

                % Plot Tow Vessel Path
                TowLat(1) = simSave.TurbinePort(1).lat;
                TowLon(1) = simSave.TurbinePort(1).lon;
                color_indices(1,1) = 1;
                for i = 2:l
                    TowStatus(i,1) = simSave.TowVessel(i).status;
                    TowLat(i,1) = simSave.TowVessel(i).lat;
                    TowLon(i,1) = simSave.TowVessel(i).lon;
                    color_indices(i,1) = round((time(i)-1)/(l-1)*(size(cmap,1)-1))+1;
                    line_color(i,:) = cmap(color_indices(i),:);
                    geoplot(app.ResultMap,[TowLat(i-1) TowLat(i)], [TowLon(i-1) TowLon(i)],'Color',line_color(i,:));
                end
            end
        end

        % Value changed function: NumTurb
        function NumTurbValueChanged(app, event)

        end

        % Callback function
        function CreatePlotButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WeatherTab = uitab(app.TabGroup);
            app.WeatherTab.Title = 'Weather';
            app.WaveHeightPlot = uiaxes(app.WeatherTab);
            title(app.WaveHeightPlot, 'Wave History')
            xlabel(app.WaveHeightPlot, 'Time')
            ylabel(app.WaveHeightPlot, 'Wind Speed (m/s)')
            zlabel(app.WaveHeightPlot, 'Z')
            app.WindSpeedPlot = uiaxes(app.WeatherTab);
            title(app.WindSpeedPlot, 'Wind History')
            xlabel(app.WindSpeedPlot, 'Time')
            ylabel(app.WindSpeedPlot, 'Significant Wave Height (m)')
            zlabel(app.WindSpeedPlot, 'Z')
            app.StartDateDatePickerLabel = uilabel(app.WeatherTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDate = uidatepicker(app.WeatherTab);
            app.StartDate.ValueChangedFcn = createCallbackFcn(app, @StartDateValueChanged, true);
            app.StartDate.Value = datetime([1949 4 1]);
            app.WeatherData = uibutton(app.WeatherTab, 'push');
            app.WeatherData.ButtonPushedFcn = createCallbackFcn(app, @WeatherDataButtonPushed, true);
            app.WeatherData.Text = 'Load Weather Data';
            app.WeatherDataEditFieldLabel = uilabel(app.WeatherTab);
            app.WeatherDataEditFieldLabel.Text = 'WeatherData';
            app.WeatherDataEditField = uieditfield(app.WeatherTab, 'text');
            app.WeatherDataEditField.Value = 'WeatherData.mat';
            app.WindDirectionPanel = uipanel(app.WeatherTab);
            app.WindDirectionPanel.Title = 'Wind Direction';
            app.WaveDirectionPanel = uipanel(app.WeatherTab);
            app.WaveDirectionPanel.Title = 'Wave Direction';
            app.LocationsTab = uitab(app.TabGroup);
            app.LocationsTab.Title = 'Locations';
            app.TurbinePortLabel = uilabel(app.LocationsTab);
            app.TurbinePortLabel.Text = 'Turbine Port';
            app.TurbinePortDropDown = uidropdown(app.LocationsTab);
            app.TurbinePortDropDown.Items = {'humboldt', 'sanfran', 'longbeach', 'searsport', 'lanouvelle', 'nigg', 'montrose', 'talbot'};
            app.TurbinePortDropDown.ValueChangedFcn = createCallbackFcn(app, @TurbinePortDropDownValueChanged, true);
            app.TurbinePortDropDown.Value = 'longbeach';
            app.LatitudeEditFieldLabel = uilabel(app.LocationsTab);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.TurbineLatitude = uieditfield(app.LocationsTab, 'numeric');
            app.TurbineLatitude.Limits = [-90 90];
            app.TurbineLatitude.Value = 33.739737;
            app.LongitudeEditFieldLabel = uilabel(app.LocationsTab);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.TurbineLongitude = uieditfield(app.LocationsTab, 'numeric');
            app.TurbineLongitude.Limits = [-180 180];
            app.TurbineLongitude.Value = -118.205367;
            app.TurbineCooridor = uibutton(app.LocationsTab, 'push');
            app.TurbineCooridor.ButtonPushedFcn = createCallbackFcn(app, @TurbineCooridorButtonPushed, true);
            app.TurbineCooridor.Text = 'Load Turbine Cooridor';
            app.TurbineCooridorEditFieldLabel = uilabel(app.LocationsTab);
            app.TurbineCooridorEditFieldLabel.Text = 'Turbine Cooridor';
            app.TurbineCooridorEditField = uieditfield(app.LocationsTab, 'text');
            app.TurbineCooridorEditField.Value = 'LongBeach_TowingCooridor.csv';
            app.SamePorts = uicheckbox(app.LocationsTab);
            app.SamePorts.ValueChangedFcn = createCallbackFcn(app, @SamePortsValueChanged, true);
            app.SamePorts.Text = 'Mooring Port same as Turbine Port';
            app.MooringCooridorEditFieldLabel = uilabel(app.LocationsTab);
            app.MooringCooridorEditFieldLabel.Text = 'Mooring Cooridor';
            app.MooringCooridorEditField = uieditfield(app.LocationsTab, 'text');
            app.MooringCooridorEditField.Value = 'Humboldt_MooringCooridor.csv';
            app.TurbinePortLabel_2 = uilabel(app.LocationsTab);
            app.TurbinePortLabel_2.Text = 'Mooring Port';
            app.MooringPortDropDown = uidropdown(app.LocationsTab);
            app.MooringPortDropDown.Items = {'humboldt', 'sanfran', 'longbeach', 'searsport', 'lanouvelle', 'nigg', 'montrose', 'talbot'};
            app.MooringPortDropDown.ValueChangedFcn = createCallbackFcn(app, @MooringPortDropDownValueChanged, true);
            app.MooringPortDropDown.Value = 'humboldt';
            app.LatitudeEditFieldLabel_2 = uilabel(app.LocationsTab);
            app.LatitudeEditFieldLabel_2.Text = 'Latitude';
            app.MooringLatitude = uieditfield(app.LocationsTab, 'numeric');
            app.MooringLatitude.Limits = [-90 90];
            app.MooringLatitude.Value = 40.807565;
            app.LongitudeEditFieldLabel_2 = uilabel(app.LocationsTab);
            app.LongitudeEditFieldLabel_2.Text = 'Longitude';
            app.MooringLongitude = uieditfield(app.LocationsTab, 'numeric');
            app.MooringLongitude.Limits = [-180 180];
            app.MooringLongitude.Value = -124.162268;
            app.MooringCooridor = uibutton(app.LocationsTab, 'push');
            app.MooringCooridor.ButtonPushedFcn = createCallbackFcn(app, @MooringCooridorPushed, true);
            app.MooringCooridor.Text = 'Load Mooring Cooridor';
            app.ArrayCoordinates = uibutton(app.LocationsTab, 'push');
            app.ArrayCoordinates.ButtonPushedFcn = createCallbackFcn(app, @ArrayCoordinatesButtonPushed, true);
            app.ArrayCoordinates.Text = 'Load Array Coordinates';
            app.ArrayCoordinatesEditFieldLabel = uilabel(app.LocationsTab);
            app.ArrayCoordinatesEditFieldLabel.Text = 'Array Coordinates';
            app.ArrayCoordinatesEditField = uieditfield(app.LocationsTab, 'text');
            app.ArrayCoordinatesEditField.Value = 'ExampleArrayLatLon.csv';
            app.MapPanel = uipanel(app.LocationsTab);
            app.MapPanel.Title = 'Map';
            app.Vessels = uitab(app.TabGroup);
            app.Vessels.Title = 'Vessels';
            app.TowVesselNameEditFieldLabel = uilabel(app.Vessels);
            app.TowVesselNameEditFieldLabel.Text = 'Tow Vessel Name';
            app.TowVesselName = uieditfield(app.Vessels, 'text');
            app.TowVesselName.ValueChangedFcn = createCallbackFcn(app, @TowVesselNameValueChanged, true);
            app.TowVesselName.Value = 'Tow';
            app.TransitSpeedkmhLabel = uilabel(app.Vessels);
            app.TransitSpeedkmhLabel.Text = 'Transit Speed (km/h)';
            app.TowTransitSpeed = uieditfield(app.Vessels, 'numeric');
            app.TowTransitSpeed.Limits = [0 Inf];
            app.TowTransitSpeed.ValueChangedFcn = createCallbackFcn(app, @TowTransitSpeedValueChanged, true);
            app.TowTransitSpeed.Value = 14;
            app.HourlyRateLabel = uilabel(app.Vessels);
            app.HourlyRateLabel.Text = 'Hourly Rate ($)';
            app.TowRate = uieditfield(app.Vessels, 'numeric');
            app.TowRate.Limits = [0 Inf];
            app.TowRate.ValueChangedFcn = createCallbackFcn(app, @TowRateValueChanged, true);
            app.TowRate.Value = 1458.33;
            app.MobilizeTimehrEditFieldLabel = uilabel(app.Vessels);
            app.MobilizeTimehrEditFieldLabel.Text = 'Mobilize Time (hr)';
            app.TowMob = uieditfield(app.Vessels, 'numeric');
            app.TowMob.Limits = [0 Inf];
            app.TowMob.ValueChangedFcn = createCallbackFcn(app, @TowMobValueChanged, true);
            app.TowMob.Value = 72;
            app.TransitWaveHeightLimitmEditFieldLabel = uilabel(app.Vessels);
            app.TransitWaveHeightLimitmEditFieldLabel.Text = 'Transit Wave Height Limit (m)';
            app.TowTransitWave = uieditfield(app.Vessels, 'numeric');
            app.TowTransitWave.Limits = [0 Inf];
            app.TowTransitWave.ValueChangedFcn = createCallbackFcn(app, @TowTransitWaveValueChanged, true);
            app.TowTransitWave.Value = 4.75;
            app.TransitWindSpeedLimitmsEditFieldLabel = uilabel(app.Vessels);
            app.TransitWindSpeedLimitmsEditFieldLabel.Text = 'Transit Wind Speed Limit (m/s)';
            app.TowTransitWind = uieditfield(app.Vessels, 'numeric');
            app.TowTransitWind.Limits = [0 Inf];
            app.TowTransitWind.ValueChangedFcn = createCallbackFcn(app, @TowTransitWindValueChanged, true);
            app.TowTransitWind.Value = 25;
            app.TowingWaveHeightLimitmEditFieldLabel = uilabel(app.Vessels);
            app.TowingWaveHeightLimitmEditFieldLabel.Text = 'Towing Wave Height Limit (m)';
            app.TowTowWave = uieditfield(app.Vessels, 'numeric');
            app.TowTowWave.Limits = [0 Inf];
            app.TowTowWave.ValueChangedFcn = createCallbackFcn(app, @TowTowWaveValueChanged, true);
            app.TowTowWave.Value = 4;
            app.TowingWindSpeedLimitmsEditFieldLabel = uilabel(app.Vessels);
            app.TowingWindSpeedLimitmsEditFieldLabel.Text = 'Towing Wind Speed Limit (m/s)';
            app.TowTowWind = uieditfield(app.Vessels, 'numeric');
            app.TowTowWind.Limits = [0 Inf];
            app.TowTowWind.ValueChangedFcn = createCallbackFcn(app, @TowTowWindValueChanged, true);
            app.TowTowWind.Value = 15;
            app.AHTSVesselNameEditFieldLabel = uilabel(app.Vessels);
            app.AHTSVesselNameEditFieldLabel.Text = 'AHTS Vessel Name';
            app.AHTSVesselName = uieditfield(app.Vessels, 'text');
            app.AHTSVesselName.ValueChangedFcn = createCallbackFcn(app, @AHTSVesselNameValueChanged, true);
            app.AHTSVesselName.Value = 'Dino';
            app.SpeedkmhEditFieldLabel = uilabel(app.Vessels);
            app.SpeedkmhEditFieldLabel.Text = 'Speed (km/h)';
            app.AHTSSpeed = uieditfield(app.Vessels, 'numeric');
            app.AHTSSpeed.Limits = [0 Inf];
            app.AHTSSpeed.ValueChangedFcn = createCallbackFcn(app, @AHTSSpeedValueChanged, true);
            app.AHTSSpeed.Value = 14;
            app.HourlyRateEditField_2Label = uilabel(app.Vessels);
            app.HourlyRateEditField_2Label.Text = 'Hourly Rate ($)';
            app.AHTSRate = uieditfield(app.Vessels, 'numeric');
            app.AHTSRate.Limits = [0 Inf];
            app.AHTSRate.ValueChangedFcn = createCallbackFcn(app, @AHTSRateValueChanged, true);
            app.AHTSRate.Value = 4166.67;
            app.MobilizeTimehrEditField_2Label = uilabel(app.Vessels);
            app.MobilizeTimehrEditField_2Label.Text = 'Mobilize Time (hr)';
            app.AHTSMob = uieditfield(app.Vessels, 'numeric');
            app.AHTSMob.Limits = [0 Inf];
            app.AHTSMob.ValueChangedFcn = createCallbackFcn(app, @AHTSMobValueChanged, true);
            app.AHTSMob.Value = 120;
            app.PreInstallWaveHeightLimitmEditFieldLabel = uilabel(app.Vessels);
            app.PreInstallWaveHeightLimitmEditFieldLabel.Text = 'Pre-Install Wave Height Limit (m)';
            app.AHTSPreInstallWave = uieditfield(app.Vessels, 'numeric');
            app.AHTSPreInstallWave.Limits = [0 Inf];
            app.AHTSPreInstallWave.ValueChangedFcn = createCallbackFcn(app, @AHTSPreInstallWaveValueChanged, true);
            app.AHTSPreInstallWave.Value = 4.3;
            app.PreInstallWindSpeedLimitmsEditFieldLabel = uilabel(app.Vessels);
            app.PreInstallWindSpeedLimitmsEditFieldLabel.Text = 'Pre-Install Wind Speed Limit (m/s)';
            app.AHTSPreInstallWind = uieditfield(app.Vessels, 'numeric');
            app.AHTSPreInstallWind.Limits = [0 Inf];
            app.AHTSPreInstallWind.ValueChangedFcn = createCallbackFcn(app, @AHTSPreInstallWindValueChanged, true);
            app.AHTSPreInstallWind.Value = 20;
            app.AnchorInstallWaveHeightLimitmEditFieldLabel = uilabel(app.Vessels);
            app.AnchorInstallWaveHeightLimitmEditFieldLabel.Text = 'Anchor Install Wave Height Limit (m)';
            app.AHTSAnchorWave = uieditfield(app.Vessels, 'numeric');
            app.AHTSAnchorWave.Limits = [0 Inf];
            app.AHTSAnchorWave.ValueChangedFcn = createCallbackFcn(app, @AHTSAnchorWaveValueChanged, true);
            app.AHTSAnchorWave.Value = 4.1;
            app.AnchorInstallWindSpeedLimitmsEditFieldLabel = uilabel(app.Vessels);
            app.AnchorInstallWindSpeedLimitmsEditFieldLabel.Text = 'Anchor Install Wind Speed Limit (m/s)';
            app.AHTSAnchorWind = uieditfield(app.Vessels, 'numeric');
            app.AHTSAnchorWind.Limits = [0 Inf];
            app.AHTSAnchorWind.ValueChangedFcn = createCallbackFcn(app, @AHTSAnchorWindValueChanged, true);
            app.AHTSAnchorWind.Value = 20;
            app.MooringInstallWaveHeightLimitmEditFieldLabel = uilabel(app.Vessels);
            app.MooringInstallWaveHeightLimitmEditFieldLabel.Text = 'Mooring Install Wave Height Limit (m)';
            app.AHTSMooringWave = uieditfield(app.Vessels, 'numeric');
            app.AHTSMooringWave.Limits = [0 Inf];
            app.AHTSMooringWave.ValueChangedFcn = createCallbackFcn(app, @AHTSMooringWaveValueChanged, true);
            app.AHTSMooringWave.Value = 4;
            app.MooringInstallWindSpeedLimitmsEditFieldLabel = uilabel(app.Vessels);
            app.MooringInstallWindSpeedLimitmsEditFieldLabel.Text = 'Mooring Install Wind Speed Limit (m/s)';
            app.AHTSMooringWind = uieditfield(app.Vessels, 'numeric');
            app.AHTSMooringWind.Limits = [0 Inf];
            app.AHTSMooringWind.ValueChangedFcn = createCallbackFcn(app, @AHTSMooringWindValueChanged, true);
            app.AHTSMooringWind.Value = 15;
            app.TransitWaveHeightLimitmEditField_2Label = uilabel(app.Vessels);
            app.TransitWaveHeightLimitmEditField_2Label.Text = 'Transit Wave Height Limit (m)';
            app.AHTSTransitWave = uieditfield(app.Vessels, 'numeric');
            app.AHTSTransitWave.Limits = [0 Inf];
            app.AHTSTransitWave.ValueChangedFcn = createCallbackFcn(app, @AHTSTransitWaveValueChanged, true);
            app.AHTSTransitWave.Value = 4.5;
            app.TransitWindSpeedLimitmsEditField_2Label = uilabel(app.Vessels);
            app.TransitWindSpeedLimitmsEditField_2Label.Text = 'Transit Wind Speed Limit (m/s)';
            app.AHTSTransitWind = uieditfield(app.Vessels, 'numeric');
            app.AHTSTransitWind.Limits = [0 Inf];
            app.AHTSTransitWind.ValueChangedFcn = createCallbackFcn(app, @AHTSTransitWindValueChanged, true);
            app.AHTSTransitWind.Value = 20;
            app.SpeedkmhEditField_2Label_2 = uilabel(app.Vessels);
            app.SpeedkmhEditField_2Label_2.Text = 'Towing Speed (km/h)';
            app.TowTowSpeed = uieditfield(app.Vessels, 'numeric');
            app.TowTowSpeed.Limits = [0 Inf];
            app.TowTowSpeed.Value = 9;
            app.Project = uitab(app.TabGroup);
            app.Project.Title = 'Project Details & Run';
            app.ResultsPlot = uiaxes(app.Project);
            title(app.ResultsPlot, 'Results')
            xlabel(app.ResultsPlot, 'Time (days)')
            ylabel(app.ResultsPlot, 'Turbines')
            zlabel(app.ResultsPlot, 'Z')
            app.ResultsSummaryLabel = uilabel(app.Project);
            app.ResultsSummaryLabel.Text = 'Results Summary';
            app.ResultsSummary = uitextarea(app.Project);
            app.ResultsSummary.Value = {'Run simulation for results'};
            app.RunSimulation = uibutton(app.Project, 'push');
            app.RunSimulation.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulation.Text = 'Run Simulation';
            app.ExportFileNamematEditFieldLabel = uilabel(app.Project);
            app.ExportFileNamematEditFieldLabel.Text = 'Export File Name (*.mat)';
            app.ExportFileName = uieditfield(app.Project, 'text');
            app.ExportFileName.ValueChangedFcn = createCallbackFcn(app, @ExportFileNameValueChanged, true);
            app.ExportFileName.Value = 'TOWTOOL_Results';
            app.NumberofLinesPerTurbineEditFieldLabel = uilabel(app.Project);
            app.NumberofLinesPerTurbineEditFieldLabel.Text = 'Number of Lines Per Turbine';
            app.NumLines = uieditfield(app.Project, 'numeric');
            app.NumLines.Limits = [0 Inf];
            app.NumLines.Value = 6;
            app.TurbineAssemblyTimehrLabel = uilabel(app.Project);
            app.TurbineAssemblyTimehrLabel.Text = 'Turbine Assembly Time (hr)';
            app.TurbineAssemblyTime = uieditfield(app.Project, 'numeric');
            app.TurbineAssemblyTime.Limits = [0 Inf];
            app.TurbineAssemblyTime.Value = 336;
            app.WetStorageLabel = uilabel(app.Project);
            app.WetStorageLabel.Text = 'Wet Storage Max. (# Turbines)';
            app.WetStorage = uieditfield(app.Project, 'numeric');
            app.WetStorage.Limits = [0 Inf];
            app.WetStorage.Value = 5;
            app.WetStorageLabel_2 = uilabel(app.Project);
            app.WetStorageLabel_2.Text = 'Turbines in Wet Storage at Start';
            app.NumTurb = uieditfield(app.Project, 'numeric');
            app.NumTurb.Limits = [0 Inf];
            app.NumTurb.ValueChangedFcn = createCallbackFcn(app, @NumTurbValueChanged, true);
            app.NumTurb.Value = 5;
            app.ResultsTable = uitable(app.Project);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.PostprocessingMap = uitab(app.TabGroup);
            app.PostprocessingMap.Title = 'Postprocessing - Map';
            app.ResultMapPanel = uipanel(app.PostprocessingMap);
            app.ResultMapPanel.Title = 'Panel';
            app.ExportFileNamematEditField_2Label = uilabel(app.PostprocessingMap);
            app.ExportFileNamematEditField_2Label.Text = 'Export File Name (*.mat)';
            app.ResultFileNameMap = uieditfield(app.PostprocessingMap, 'text');
            app.ResultFileNameMap.ValueChangedFcn = createCallbackFcn(app, @ResultFileNameMapValueChanged, true);
            app.ResultFileNameMap.Value = 'TOWTOOL_Results';
            app.ChartTypeDropDownLabel = uilabel(app.PostprocessingMap);
            app.ChartTypeDropDownLabel.Text = 'Chart Type';
            app.MapType = uidropdown(app.PostprocessingMap);
            app.MapType.Items = {'AHTSMap', 'TowMap'};
            app.MapType.ValueChangedFcn = createCallbackFcn(app, @MapTypeValueChanged, true);
            app.MapType.Value = 'AHTSMap';
            app.CreateMap = uibutton(app.PostprocessingMap, 'push');
            app.CreateMap.ButtonPushedFcn = createCallbackFcn(app, @CreateMapButtonPushed, true);
            app.CreateMap.Text = 'Create Chart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MORBIT_SingleRun_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end