classdef TOWTOOL_GUI_82 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AboutTab                        matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Toolboxes                       matlab.ui.control.Label
        Contact                         matlab.ui.control.Label
        AssumptionsLabel                matlab.ui.control.Label
        QuickStartLabel                 matlab.ui.control.Label
        QuickStartDescription           matlab.ui.control.Label
        TowOutWorkflowToolkitforOffshoreOperationsandLogisticsLabel  matlab.ui.control.Label
        AssumptionsDescription          matlab.ui.control.Label
        Disclaimer                      matlab.ui.control.Label
        TOWTOOLv10Label                 matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        WeatherTab                      matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        WeatherLabel3                   matlab.ui.control.Label
        WeatherLabel1                   matlab.ui.control.Label
        WeatherLabel2                   matlab.ui.control.Label
        WaveDirectionPanel              matlab.ui.container.Panel
        WindDirectionPanel              matlab.ui.container.Panel
        WeatherDataEditField            matlab.ui.control.EditField
        WeatherDataEditFieldLabel       matlab.ui.control.Label
        WeatherData                     matlab.ui.control.Button
        StartDate                       matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        WindSpeedPlot                   matlab.ui.control.UIAxes
        WaveHeightPlot                  matlab.ui.control.UIAxes
        LocationsTab                    matlab.ui.container.Tab
        Image3_2                        matlab.ui.control.Image
        LocationsLabel2                 matlab.ui.control.Label
        LocationsLabel1                 matlab.ui.control.Label
        TransitCorridorPanel            matlab.ui.container.Panel
        LoadTransitCorridor             matlab.ui.control.Button
        TransitCorridorType             matlab.ui.control.DropDown
        SelectCorridorLabel_4           matlab.ui.control.Label
        TransitCorridorNeededLabel      matlab.ui.control.Label
        TransitCorridorFile             matlab.ui.control.EditField
        CorridorLabel                   matlab.ui.control.Label
        PlotTransitCorridor             matlab.ui.control.Button
        MapPanel                        matlab.ui.container.Panel
        ArrayLocationsPanel             matlab.ui.container.Panel
        PlotArray                       matlab.ui.control.Button
        ArrayCoordinatesFile            matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        LoadArrayCoordinates            matlab.ui.control.Button
        PlatformPortPanel               matlab.ui.container.Panel
        LoadPlatformCorridor            matlab.ui.control.Button
        PlatformCorridorType            matlab.ui.control.DropDown
        SelectCorridorLabel             matlab.ui.control.Label
        PlatformCorridorFile            matlab.ui.control.EditField
        PlatformCorridorLabel           matlab.ui.control.Label
        PlotPlatformCorridor            matlab.ui.control.Button
        PlatformPortDropDown            matlab.ui.control.DropDown
        PortDropDownLabel               matlab.ui.control.Label
        PlatformLongitude               matlab.ui.control.NumericEditField
        LonLabel                        matlab.ui.control.Label
        PlatformLatitude                matlab.ui.control.NumericEditField
        LatLabel                        matlab.ui.control.Label
        TurbinePortPanel                matlab.ui.container.Panel
        LoadTurbineCorridor             matlab.ui.control.Button
        TurbineCorridorType             matlab.ui.control.DropDown
        SelectCorridorLabel_2           matlab.ui.control.Label
        PortsSame                       matlab.ui.control.CheckBox
        TurbineLongitude                matlab.ui.control.NumericEditField
        LonLabel_2                      matlab.ui.control.Label
        TurbineLatitude                 matlab.ui.control.NumericEditField
        LatLabel_2                      matlab.ui.control.Label
        PlotTurbineCorridor             matlab.ui.control.Button
        TurbineCorridorFile             matlab.ui.control.EditField
        TurbineCorridorLabel            matlab.ui.control.Label
        TurbinePortDropDown             matlab.ui.control.DropDown
        TurbinePortLabel                matlab.ui.control.Label
        MooringMarshalPortPanel         matlab.ui.container.Panel
        LoadMooringCorridor             matlab.ui.control.Button
        MooringCorridorType             matlab.ui.control.DropDown
        SelectCorridorLabel_3           matlab.ui.control.Label
        MooringPortSame                 matlab.ui.control.CheckBox
        MooringPortDropDown             matlab.ui.control.DropDown
        TurbinePortLabel_2              matlab.ui.control.Label
        MooringLongitude                matlab.ui.control.NumericEditField
        LonLabel_3                      matlab.ui.control.Label
        MooringLatitude                 matlab.ui.control.NumericEditField
        LatLabel_3                      matlab.ui.control.Label
        PlotMooringCorridor             matlab.ui.control.Button
        MooringCorridorFile             matlab.ui.control.EditField
        MooringCorridorLabel            matlab.ui.control.Label
        Vessels                         matlab.ui.container.Tab
        Image3_3                        matlab.ui.control.Image
        LocationsLabel2_2               matlab.ui.control.Label
        WeatherLabel1_2                 matlab.ui.control.Label
        AHTSVesselPanel                 matlab.ui.container.Panel
        AHTSStorage                     matlab.ui.control.NumericEditField
        CapacityfullmooringsystemsLabel  matlab.ui.control.Label
        AHTSMooringWind                 matlab.ui.control.NumericEditField
        MooringInstallWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        AHTSTransitWave                 matlab.ui.control.NumericEditField
        TransitWaveHeightLimitmEditField_2Label  matlab.ui.control.Label
        AHTSTransitWind                 matlab.ui.control.NumericEditField
        TransitWindSpeedLimitmsEditField_2Label  matlab.ui.control.Label
        AHTSPreInstallWind              matlab.ui.control.NumericEditField
        PreInstallWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        AHTSAnchorWave                  matlab.ui.control.NumericEditField
        AnchorInstallWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        AHTSAnchorWind                  matlab.ui.control.NumericEditField
        AnchorInstallWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        AHTSMooringWave                 matlab.ui.control.NumericEditField
        MooringInstallWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        AHTSPreInstallWave              matlab.ui.control.NumericEditField
        PreInstallWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        AHTSRate                        matlab.ui.control.NumericEditField
        HourlyRateEditField_2Label      matlab.ui.control.Label
        AHTSSpeed                       matlab.ui.control.NumericEditField
        SpeedkmhEditFieldLabel          matlab.ui.control.Label
        AHTSVesselName                  matlab.ui.control.EditField
        AHTSVesselNameEditFieldLabel    matlab.ui.control.Label
        AHTSMob                         matlab.ui.control.NumericEditField
        MobilizeTimehrEditField_2Label  matlab.ui.control.Label
        TowVesselsPanel                 matlab.ui.container.Panel
        TowSelect                       matlab.ui.control.DropDown
        SelectTowVesselNumberDropDownLabel  matlab.ui.control.Label
        TowNum                          matlab.ui.control.Spinner
        NumberofTowVesselsLabel         matlab.ui.control.Label
        TowVessel1Panel                 matlab.ui.container.Panel
        TowVesselName                   matlab.ui.control.EditField
        TowVesselNameEditFieldLabel     matlab.ui.control.Label
        TowRate                         matlab.ui.control.NumericEditField
        HourlyRateLabel                 matlab.ui.control.Label
        TowMob                          matlab.ui.control.NumericEditField
        MobilizeTimehrEditFieldLabel    matlab.ui.control.Label
        TowTransitSpeed                 matlab.ui.control.NumericEditField
        TransitSpeedkmhLabel            matlab.ui.control.Label
        TowTowSpeed                     matlab.ui.control.NumericEditField
        SpeedkmhEditField_2Label_2      matlab.ui.control.Label
        TowTransitWave                  matlab.ui.control.NumericEditField
        TransitWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        TowTransitWind                  matlab.ui.control.NumericEditField
        TransitWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        TowTowWave                      matlab.ui.control.NumericEditField
        TowingWaveHeightLimitmEditFieldLabel  matlab.ui.control.Label
        TowTowWind                      matlab.ui.control.NumericEditField
        TowingWindSpeedLimitmsEditFieldLabel  matlab.ui.control.Label
        TowLimit                        matlab.ui.control.DropDown
        LimitedwonttowanassembledturbineLabel  matlab.ui.control.Label
        ProjectDetailsTab               matlab.ui.container.Tab
        Image3_4                        matlab.ui.control.Image
        MooringPortRate                 matlab.ui.control.NumericEditField
        TurbineAssemblyTimehrLabel_7    matlab.ui.control.Label
        TurbinePortRate                 matlab.ui.control.NumericEditField
        TurbineAssemblyTimehrLabel_6    matlab.ui.control.Label
        PlatformPortRate                matlab.ui.control.NumericEditField
        TurbineAssemblyTimehrLabel_5    matlab.ui.control.Label
        TurbineWetStorageInit           matlab.ui.control.NumericEditField
        WetStorageLabel_8               matlab.ui.control.Label
        TurbineWetStorageMax            matlab.ui.control.NumericEditField
        WetStorageLabel_7               matlab.ui.control.Label
        TurbineTime                     matlab.ui.control.NumericEditField
        TurbineAssemblyTimehrLabel_4    matlab.ui.control.Label
        PlatformWetStorageInit          matlab.ui.control.NumericEditField
        WetStorageLabel_6               matlab.ui.control.Label
        PlatformWetStorageMax           matlab.ui.control.NumericEditField
        WetStorageLabel_5               matlab.ui.control.Label
        PlatformTime                    matlab.ui.control.NumericEditField
        TurbineAssemblyTimehrLabel_3    matlab.ui.control.Label
        NumLines                        matlab.ui.control.NumericEditField
        NumberofMooringLinesPerTurbineLabel_2  matlab.ui.control.Label
        Project                         matlab.ui.container.Tab
        Image3_5                        matlab.ui.control.Image
        ProjectRunDescription           matlab.ui.control.Label
        LoadPreviousRun                 matlab.ui.control.Button
        ResultsTable                    matlab.ui.control.Table
        ExportFileName                  matlab.ui.control.EditField
        ProjectFileNamematLabel         matlab.ui.control.Label
        RunSimulation                   matlab.ui.control.Button
        ResultsSummary                  matlab.ui.control.TextArea
        ResultsSummaryLabel             matlab.ui.control.Label
        ResultsPlot                     matlab.ui.control.UIAxes
        PostprocessingMap               matlab.ui.container.Tab
        Image3_6                        matlab.ui.control.Image
        Tow5CheckBox                    matlab.ui.control.CheckBox
        Tow4CheckBox                    matlab.ui.control.CheckBox
        Tow3CheckBox                    matlab.ui.control.CheckBox
        Tow2CheckBox                    matlab.ui.control.CheckBox
        IncludeinMapLabel               matlab.ui.control.Label
        Tow1CheckBox                    matlab.ui.control.CheckBox
        AHTSCheckBox                    matlab.ui.control.CheckBox
        CreateMap                       matlab.ui.control.Button
        ResultFileNameMap               matlab.ui.control.EditField
        ExportFileNamematEditField_2Label  matlab.ui.control.Label
        ResultMapPanel                  matlab.ui.container.Panel
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
       WindRose
       WaveRose
       MapAxesObject
       ResultMap
       TowParticulars
       PortTable
       TurbineCorr
       TurbineCoord
       PlatformCorr
       PlatformCoord
       MooringCorr
       MooringCoord
       TransitCorr
       ArrayCoord

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WindRose = polaraxes(app.WindDirectionPanel);
            app.WaveRose = polaraxes(app.WaveDirectionPanel);
            app.MapAxesObject = geoaxes(app.MapPanel);
            geobasemap (app.MapAxesObject, 'topographic')
            app.ResultMap = geoaxes(app.ResultMapPanel);
            geobasemap (app.ResultMap, 'topographic');
            % Define Tow Vessel Particulars in a table
            Name = ["Tow1"; "Tow2"; "Tow3"; "Tow4"; "Tow5"];
            Rate = [1400; 1400; 1400; 1400;1400]; %[$/h] Hourly rate (daily rate/24)
            Mob = [72; 72; 72; 72; 72]; %[h] Mobilization time
            TransitSpeed = [14; 14; 14; 14; 14]; %[km/h] Vessel Cruising Speed during transit
            TowSpeed = [9; 9; 9; 9; 9]; %[km/h] Vessel Cruising Speed while towing a platform/turbine
            TransitWave = [4.75; 4.75; 4.75; 4.75; 4.75]; %[m] Significant wave height limit during transit
            TransitWind = [25; 25; 25; 25; 25]; %[m/s] Wind speed limit during transit
            TowWave = [4; 4; 4; 4; 4]; %[m] Significant wave height limit while towing a platform/turbine
            TowWind = [15; 15; 15; 15; 15]; %[m/s] Wind speed height while towing a platform/turbine
            TowLimit = [0; 0; 0; 0; 0]; % [y/n] Towing vessel limited (can't tow a fully assembled turbine)
            app.TowParticulars = table(Name, Rate, Mob, TransitSpeed, TowSpeed, TransitWave, TransitWind, TowWave, TowWind, TowLimit, 'VariableNames',{'Name', 'Rate', 'Mob', 'TransitSpeed', 'TowSpeed', 'TransitWave', 'TransitWind', 'TowWave', 'TowWind', 'TowLimit'});
            PortList =     {'humboldt', 40.807565, -124.162268;
                            'sanFran', 37.794402, -122.391938;
                            'longbeach', 33.739737, -118.205367;
                            'searsport', 44.451783, -68.902169;
                            'lanouvelle', 43.01667, 3.05;
                            'nigg', 57.70056, -4.03846;
                            'montrose', 56.7, -2.4667;
                            'aberdeen', 57.14141, -2.0698;
                            'talbot', 51.5947, -3.7841}; %Name, Lat, Lon
            app.PortTable = table(cell2mat(PortList(:, 2)), cell2mat(PortList(:, 3)), ...
                'VariableNames', {'Latitude', 'Longitude'}, ...
                'RowNames', PortList(:, 1));
            app.PlatformPortDropDown.Items = [app.PortTable.Properties.RowNames; 'other'];
            app.TurbinePortDropDown.Items = [app.PortTable.Properties.RowNames; 'other'];
            app.MooringPortDropDown.Items = [app.PortTable.Properties.RowNames; 'other'];
        end

        % Callback function
        function TurbinePortDropDownOpening(app, event)

        end

        % Value changed function: TurbinePortDropDown
        function TurbinePortDropDownValueChanged(app, event)
            % Update Latitude and Longitude
            if strcmp(app.TurbinePortDropDown.Value,"other")
                app.TurbineLatitude.Enable = 'on';
                app.TurbineLongitude.Enable = 'on';
            else
                app.TurbineLatitude.Value = app.PortTable{char(app.TurbinePortDropDown.Value), 'Latitude'};
                app.TurbineLongitude.Value = app.PortTable{char(app.TurbinePortDropDown.Value), 'Longitude'};
                app.TurbineLatitude.Enable = 'off';
                app.TurbineLongitude.Enable = 'off';
            end
            if ishandle(app.TurbineCorr)
                delete(app.TurbineCorr);
            end
            if ishandle(app.TurbineCoord)
                delete(app.TurbineCoord);
            end
            if ishandle(app.PlatformCorr)
                delete(app.PlatformCorr);
            end
            if ishandle(app.PlatformCoord)
                delete(app.PlatformCoord);
            end
            if app.TurbineLatitude.Value == app.PlatformLatitude.Value && app.TurbineLongitude.Value == app.PlatformLongitude.Value
                app.PortsSame.Value = 1;
                app.TurbinePortDropDown.Value  = app.PlatformPortDropDown.Value;
                app.TurbineLatitude.Value = app.PlatformLatitude.Value;
                app.TurbineLongitude.Value = app.PlatformLongitude.Value;
                app.TurbineCorridorFile.Value = app.PlatformCorridorFile.Value;
                app.TurbinePortDropDown.Enable  = 'off';
                app.TurbineLatitude.Enable = 'off';
                app.TurbineLongitude.Enable = 'off';
                app.PlotTurbineCorridor.Enable = 'off';
                app.TurbineCorridorFile.Enable = 'off';
                app.TransitCorridorFile.Enable = 'off';
                app.PlotTransitCorridor.Enable = 'off';
                app.PlatformTime.Enable = 'off';
                app.PlatformWetStorageInit.Enable = 'off';
                app.PlatformWetStorageMax.Enable = 'off';
                app.PlatformPortRate.Value = 0;
                app.PlatformPortRate.Enable = 'off';
                app.TurbineCorridorType.Value = app.PlatformCorridorType.Value;
                app.TurbineCorridorType.Enable = 'off';  
                app.TransitCorridorType.Enable = 'off';
                app.LoadTurbineCorridor.Enable = 'off';
                app.LoadTransitCorridor.Enable = 'off';
                app.TowLimit.Enable = 'off';
                app.TowParticulars.TowLimit = [0; 0; 0; 0; 0];
                if ishandle(app.TransitCorr)
                    delete(app.TransitCorr);
                end
            end
            if app.MooringPortSame.Value == 1
                app.MooringPortDropDown.Value  = app.TurbinePortDropDown.Value;
                app.MooringLatitude.Value = app.TurbineLatitude.Value;
                app.MooringLongitude.Value = app.TurbineLongitude.Value;
                app.MooringCorridorFile.Value = app.TurbineCorridorFile.Value;
                app.MooringCorridorType.Value = app.TurbineCorridorType.Value;
            elseif app.TurbineLatitude.Value == app.MooringLatitude.Value && app.TurbineLongitude.Value == app.MooringLongitude.Value
                app.MooringPortSame.Value = 1;
                app.MooringPortDropDown.Value  = app.TurbinePortDropDown.Value;
                app.MooringLatitude.Value = app.TurbineLatitude.Value;
                app.MooringLongitude.Value = app.TurbineLongitude.Value;
                app.MooringCorridorFile.Value = app.TurbineCorridorFile.Value;
                app.MooringPortDropDown.Enable  = 'off';
                app.MooringLatitude.Enable = 'off';
                app.MooringLongitude.Enable = 'off';
                app.PlotMooringCorridor.Enable = 'off';
                app.MooringCorridorFile.Enable = 'off';
                app.MooringPortRate.Value = 0;
                app.MooringPortRate.Enable = 'off';
                app.MooringCorridorType.Enable = 'off';
                app.LoadMooringCorridor.Enable = 'off';
                if ishandle(app.MooringCorr)
                    delete(app.MooringCorr);
                end
                if ishandle(app.MooringCoord)
                    delete(app.MooringCoord);
                end
            end
        end

        % Value changed function: MooringPortSame
        function MooringPortSameValueChanged(app, event)
            if app.MooringPortSame.Value == 1
                app.MooringPortDropDown.Value  = app.TurbinePortDropDown.Value;
                app.MooringLatitude.Value = app.TurbineLatitude.Value;
                app.MooringLongitude.Value = app.TurbineLongitude.Value;
                app.MooringCorridorFile.Value = app.TurbineCorridorFile.Value;
                app.MooringPortDropDown.Enable  = 'off';
                app.MooringLatitude.Enable = 'off';
                app.MooringLongitude.Enable = 'off';
                app.PlotMooringCorridor.Enable = 'off';
                app.MooringCorridorFile.Enable = 'off';
                app.MooringPortRate.Value = 0;
                app.MooringPortRate.Enable = 'off';
                app.MooringCorridorType.Enable = 'off';
                app.LoadMooringCorridor.Enable = 'off';
                if ishandle(app.MooringCorr)
                    delete(app.MooringCorr);
                end
                if ishandle(app.MooringCoord)
                    delete(app.MooringCoord);
                end

            elseif app.MooringPortSame.Value == 0
                app.PlotMooringCorridor.Enable = 'on';
                app.MooringCorridorType.Enable = 'on';
                app.MooringPortRate.Enable = 'on';
                if strcmp(app.MooringCorridorType.Value,"Direct")
                    app.MooringPortDropDown.Enable  = 'on';
                    if strcmp(app.MooringPortDropDown.Value,"other")
                        app.MooringLatitude.Enable = 'on';
                        app.MooringLongitude.Enable = 'on';
                    end
                elseif strcmp(app.MooringCorridorType.Value,"CSV")
                    app.LoadMooringCorridor.Enable = 'on';
                end

            end
 
        end

        % Value changed function: MooringPortDropDown
        function MooringPortDropDownValueChanged(app, event)
            % Update Latitude and Longitudee
            if strcmp(app.MooringPortDropDown.Value,"other")
                app.MooringLatitude.Enable = 'on';
                app.MooringLongitude.Enable = 'on';
            else
                app.MooringLatitude.Value = app.PortTable{char(app.MooringPortDropDown.Value), 'Latitude'};
                app.MooringLongitude.Value = app.PortTable{char(app.MooringPortDropDown.Value), 'Longitude'};
                app.MooringLatitude.Enable = 'off';
                app.MooringLongitude.Enable = 'off';
            end
            if app.MooringLatitude.Value == app.TurbineLatitude.Value && app.MooringLongitude.Value == app.TurbineLongitude.Value
                app.MooringPortSame.Value = 1;
                app.MooringPortDropDown.Value  = app.TurbinePortDropDown.Value;
                app.MooringLatitude.Value = app.TurbineLatitude.Value;
                app.MooringLongitude.Value = app.TurbineLongitude.Value;
                app.MooringCorridorFile.Value = app.TurbineCorridorFile.Value;
                app.MooringPortDropDown.Enable  = 'off';
                app.MooringLatitude.Enable = 'off';
                app.MooringLongitude.Enable = 'off';
                app.PlotMooringCorridor.Enable = 'off';
                app.MooringCorridorFile.Enable = 'off';
                app.MooringPortRate.Value = 0;
                app.MooringPortRate.Enable = 'off';
                app.MooringCorridorType.Enable = 'off';
                app.LoadMooringCorridor.Enable = 'off';
                if ishandle(app.MooringCorr)
                    delete(app.MooringCorr);
                end
                if ishandle(app.MooringCoord)
                    delete(app.MooringCoord);
                end
            end
            if ishandle(app.MooringCorr)
                delete(app.MooringCorr);
            end
            if ishandle(app.MooringCoord)
                delete(app.MooringCoord);
            end
        end

        % Button pushed function: WeatherData
        function WeatherDataButtonPushed(app, event)
            % This is weather data from the ERA5 dataset from the CDS API.
            % the wind speed from the original data was scaled from 100m to 10m winds using a scaling factor of 0.14.
            [weatherdatafile, weatherdatapath] = uigetfile('*.csv'); %open a mat file
            app.WeatherDataEditField.Value = weatherdatafile;
            % Load
            try 
                weatherData = readtable(app.WeatherDataEditField.Value);
            catch
                error("ERROR: Weather data not loaded. Ensure file type is *.csv.")
            end
            % Update Plots
            try
                plot(app.WaveHeightPlot, weatherData.t, weatherData.SignificantWaveHeight_m_);
                plot(app.WindSpeedPlot,weatherData.t,weatherData.WindSpeed_m_s_);
                polarhistogram(app.WindRose,deg2rad(weatherData.WindDirection_deg_),deg2rad(0:10:360))
                polarhistogram(app.WaveRose,deg2rad(weatherData.WaveDirection_deg_),deg2rad(0:10:360))
            catch
                warning('ERROR: Weather data not in correct format. Check input file. Dont change the headers.')
            end
        end

        % Button pushed function: PlotTurbineCorridor
        function PlotTurbineCorridorButtonPushed(app, event)
            if ishandle(app.TurbineCorr)
                delete(app.TurbineCorr);
            end
            if ishandle(app.TurbineCoord)
                delete(app.TurbineCoord);
            end
            Port = [app.TurbineLatitude.Value, app.TurbineLongitude.Value];
            if strcmp(app.TurbineCorridorType.Value,'CSV')
                % Load data
                Corridor = readmatrix(app.TurbineCorridorFile.Value);
            elseif strcmp(app.TurbineCorridorType.Value,'Direct')
                try
                    arr = readmatrix(app.ArrayCoordinatesFile.Value);
                catch
                    warning('ERROR: Array data needed to plot a direct corridor. Please load array coordinates.')
                end
                difLat = Port(1) - arr(:,6);
                difLon = Port(2) - arr(:,7);
                [minLat,LatIdx] = min(abs(difLat));
                [minLon,LonIdx] = min(abs(difLon));
                %Define Towing Corridor
                Corridor = [Port(1), Port(2);
                    arr(LatIdx,6),arr(LonIdx,7)]; 
            end
            % Plot on map
            hold(app.MapAxesObject,'on')
            app.TurbineCorr = geoplot(app.MapAxesObject,Corridor(:,1),Corridor(:,2),'-g');
            app.TurbineCoord = geoscatter(app.MapAxesObject,Port(1),Port(2),'g');
            hold(app.MapAxesObject,'off')
            
        end

        % Button pushed function: PlotMooringCorridor
        function PlotMooringCorridorPushed(app, event)
            if ishandle(app.MooringCorr)
                delete(app.MooringCorr);
            end
            if ishandle(app.MooringCoord)
                delete(app.MooringCoord);
            end
            Port = [app.MooringLatitude.Value, app.MooringLongitude.Value];
            if strcmp(app.MooringCorridorType.Value,'CSV')
                % Load data
                Corridor = readmatrix(app.MooringCorridorFile.Value);
            elseif strcmp(app.MooringCorridorType.Value,'Direct')
                try
                    arr = readmatrix(app.ArrayCoordinatesFile.Value);
                catch
                    warning('ERROR: Array data needed to plot a direct corridor. Please load array coordinates.')
                end
                difLat = Port(1) - arr(:,6);
                difLon = Port(2) - arr(:,7);
                [minLat,LatIdx] = min(abs(difLat));
                [minLon,LonIdx] = min(abs(difLon));
                %Define Towing Corridor
                Corridor = [Port(1), Port(2);
                    arr(LatIdx,6),arr(LonIdx,7)]; 
            end
            % Plot on map
            hold(app.MapAxesObject,'on')
            app.MooringCorr = geoplot(app.MapAxesObject,Corridor(:,1),Corridor(:,2),'-b');
            app.MooringCoord = geoscatter(app.MapAxesObject,Port(1),Port(2),'b');
            hold(app.MapAxesObject,'off')

        end

        % Button pushed function: LoadArrayCoordinates
        function LoadArrayCoordinatesButtonPushed(app, event)
            try
                [arrayfile,arraypath] = uigetfile('*.csv');  %open a csv file
                app.ArrayCoordinatesFile.Value = arrayfile;
            catch
                warning('ERROR: Corridor data not loaded. Ensure file type is *.csv.')
            end   
            % Load data
            arr = readmatrix(app.ArrayCoordinatesFile.Value);
            arrSize = size(arr,1);
            app.PlatformWetStorageMax.Limits = [1, arrSize];
            app.TurbineWetStorageMax.Limits = [1, arrSize];
            if ishandle(app.ArrayCoord)
                delete(app.ArrayCoord);
            end
        end

        % Button pushed function: RunSimulation
        function RunSimulationButtonPushed(app, event)
            disp('Simulation Running')
            app.ResultsSummary.Value = 'Simulation Running';
            
            % Check PortsSame
            if app.PortsSame.Value == 1
               
            elseif app.PlatformLatitude.Value == app.TurbineLatitude.Value && app.PlatformLongitude.Value == app.TurbineLongitude.Value
                error('Error: The Turbine Integration Port is the same as the Platform Construction Port. Please Check the "Turbine Port same as Platform Port" box')
            elseif strcmp(app.PlatformPortDropDown.Value, app.TurbinePortDropDown.Value)
                error('Error: The Turbine Integration Port is the same as the Platform Construction Port. Please Check the "Turbine Port same as Platform Port" box')
            end

            %% Weather Inputs
            % This is weather data from the ERA5 dataset from the CDS API.
            % the wind speed from the original data was scaled from 100m to 10m winds using a scaling factor of 0.14.

            try 
                weatherData = readtable(app.WeatherDataEditField.Value);
            catch
                error("ERROR: weather data not loaded. Check file type")
            end
            wave.t = weatherData.t;
            wave.HsTotal = weatherData.SignificantWaveHeight_m_;
            wave.TpTotal = weatherData.PeakWavePeriod_s_;
            wave.DmTotal = weatherData.WaveDirection_deg_;
            wind.t = weatherData.t;
            wind.Spd = weatherData.WindSpeed_m_s_;
            wind.Dir = weatherData.WindDirection_deg_;

            % Set the start time of the simulation.
            startTime = datetime(app.StartDate.Value);

            %% Location Inputs
            % Array - Load a csv from a shp file with lat, lon, and depth
            arr = readmatrix(app.ArrayCoordinatesFile.Value);
            arraySize = size(arr,1); % The number of turbines in the array
    
            % if water depth is <100 - increase to 100
            arr(:,3) = abs(arr(:,3));
            for j = 1:arraySize
                if arr(j,3) < 100
                    arr(j,3) = 100;
                end
            end

            %% Weather
            % Sample the weather data between certain dates for processing speed.
            % providing the entire weather data set, if it is long, can slow down the simulation a lot.
            % The "+ years(3)" may need to be modified depending on your WEA and the number of turbines.
            % This number is currently based on a 50 turbine array in California.
            % If there is a an index error in the weather agent, this number should be increased.
            idx = wave.t > startTime & wave.t < startTime + years(10);
            wnames = fieldnames(wave);
            for w = 1:length(wnames)
                wave.(wnames{w}) = wave.(wnames{w})(idx);
            end
            wnames = fieldnames(wind);
            for w = 1:length(wnames)
                wind.(wnames{w}) = wind.(wnames{w})(idx);
            end

            %% Create the simulation structure
            % initialize the simulation step. This structure is passed between agents at each step of the simulation and is the way
            % that agents communicate with one another, as well as where information about each time step is extracted.
            simStep = struct();
            simStep.time = 0;
            simStep.proj = wgs84Ellipsoid("km");

            % simStep.array defines all of the simulation-important information about the WEA.
            % array is lat, lon, status, id, completion time, depth (m)
            % status can be as follows:
            % 0 - Nothing installed
            % 1 - Mooring Install In Progress
            % 2 - Moorings installed
            % 3 - Turbine installed

            simStep.array = [arr(:, [6,7]),...
                zeros(size((1:arraySize)')),...
                (1:arraySize)',...
                zeros(size((1:arraySize)')), abs(arr(:, 3))];
            simStep.mooringsIn = [simStep.array(:, 4), zeros(size(simStep.array,1), 6)];

            % instantiating communications throughout the simulation.
            simStep.loadTurbine   = false(app.TowNum.Value,1); % Is the tow vessel loading a turbine
            simStep.loadPlatform  = false(app.TowNum.Value,1); % Is the tow vessel loading a platform
            simStep.loadPlat      = false(app.TowNum.Value,1); % Is the tow vessel unloading a platform
            simStep.loadMoorings  = false; % Is the AHTS loading mooring lines
            simStep.nMoorings     = 0; % number of moorings provided to ahts by the mooring port
            simStep.nTurbines     = app.TurbineWetStorageInit.Value; % number of fully assembled turbines in wet storage at turbine port
            simStep.nPlatforms    = app.PlatformWetStorageInit.Value; % number of platforms (without turbines) in wet storage at platform port
            simStep.nPlat         = 0; % number of platforms in wet storage at turbine port (need to be assembled)
            simStep.wetStorage_Turbines = app.TurbineWetStorageMax.Value; %Wet storage max capacity
            simStep.wetStorage_Platforms = app.PlatformWetStorageMax.Value; %Wet storage max capacity
            simStep.numMoorings   = app.NumLines.Value; % provides number of moorings to the sim
            simStep.AHTSonSite    = false; % tells the towing vessel if the AHTS is on site for hook up
            simStep.towInfo       = -inf(app.TowNum.Value,3); % provides towing information to the AHTS
                %COLUMN 1: ID of turbine (999 if no turbine)
                %COLUMN 2: Time until arrival at array (if towing between turbine port & array)
                %COLUMN 3: Time until arrival at turbine port (if towing between ports)
                %ROW DETERMINED BY TOWING VESSEL NO
            simStep.towWait       = true; % tells the vessel that the AHTS is too far away for a tow out to start
            simStep.timeToTurbine = 0; % provide information about turbine assembly so the AHTS can leave for periods of time
            simStep.forecast      = []; % provides an empty array for the forecast, overwritten if there is a weather module
            simStep.assembled     = 0; %
            simStep.AHTSHelpIdx   = 998; % No of tow vessel AHTS is assisting (998 if none)

            if any(app.TowParticulars.TowLimit == 1)
                simStep.towLim = 1; % if one of the towing vessels is limited
            else 
                simStep.towLim = 0;
            end

            %% Vessel Corridor Info
            % To reduce the number of calculations within the simulation, the corridor travel time is calculated ahead and provided
            % to vessels in the following structure.
            
            % Mooring Marshal Port & AHTS Corridor
            if strcmp(app.MooringCorridorType.Value,'CSV')
                MooringCorridor = readmatrix(string(app.MooringCorridorFile.Value));
                MooringMarshalPort = [MooringCorridor(1,1),MooringCorridor(1,2), app.MooringPortRate.Value];
            elseif strcmp(app.MooringCorridorType.Value,'Direct')
                MooringMarshalPort = [app.MooringLatitude.Value,app.MooringLongitude.Value,app.MooringPortRate.Value];
                MooringCorridor = [
                    MooringMarshalPort(1:2)];
            end
            dist = 0;
            if length(MooringCorridor(:,1))>=2
                for c = 2:length(MooringCorridor(:,1))
                    dist = dist + distance(MooringCorridor(c-1,1), MooringCorridor(c-1,2), ...
                        MooringCorridor(c,1), MooringCorridor(c,2), simStep.proj);
                end
                MooringCorridorInfo = struct('rallyPoint', MooringCorridor(end, 1:2), 'dist', round(dist));
            else
                MooringCorridorInfo = struct([]);
            end

            % Turbine Integration Port & Towing Corridor
            if app.PortsSame.Value == 0
                if strcmp(app.TurbineCorridorType.Value,'CSV')
                    TurbineCorridor = readmatrix(string(app.TurbineCorridorFile.Value));
                    TurbineIntegrationPort = [TurbineCorridor(1,1),TurbineCorridor(1,2), app.TurbinePortRate.Value];
                elseif strcmp(app.TurbineCorridorType.Value,'Direct')
                    TurbineIntegrationPort = [app.TurbineLatitude.Value,app.TurbineLongitude.Value,app.TurbinePortRate.Value];
                    TurbineCorridor = [
                        TurbineIntegrationPort(1:2)];
                end
                dist = 0;
                if length(TurbineCorridor(:,1))>=2
                    for c = 2:length(TurbineCorridor(:,1))
                        dist = dist + distance(TurbineCorridor(c-1,1), TurbineCorridor(c-1,2), ...
                            TurbineCorridor(c,1), TurbineCorridor(c,2), simStep.proj);
                    end
                    TurbineCorridorInfo = struct('rallyPoint', TurbineCorridor(end, 1:2), 'dist', round(dist));
                else
                    TurbineCorridorInfo = struct([]);
                end
            end

            % Platform Construction Port & Towing Corridor
            if strcmp(app.PlatformCorridorType.Value,'CSV')
                PlatformCorridor = readmatrix(string(app.PlatformCorridorFile.Value));
                PlatformConstructionPort = [PlatformCorridor(1,1),PlatformCorridor(1,2), app.PlatformPortRate.Value];
            elseif strcmp(app.PlatformCorridorType.Value,'Direct')
                PlatformConstructionPort = [app.PlatformLatitude.Value,app.PlatformLongitude.Value,app.PlatformPortRate.Value];
                if app.PortsSame.Value == 1
                    PlatformCorridor = [PlatformConstructionPort(1,1), PlatformConstructionPort(1,2)];
                else 
                    PlatformCorridor = [PlatformConstructionPort(1,1), PlatformConstructionPort(1,2);
                        TurbineIntegrationPort(1,1),TurbineIntegrationPort(1,2)];
                end
            end
            dist = 0;
            if length(PlatformCorridor(:,1))>=2
                for c = 2:length(PlatformCorridor(:,1))
                    dist = dist + distance(PlatformCorridor(c-1,1), PlatformCorridor(c-1,2), ...
                        PlatformCorridor(c,1), PlatformCorridor(c,2), simStep.proj);
                end
                PlatformCorridorInfo = struct('rallyPoint', PlatformCorridor(end, 1:2), 'dist', round(dist));
            else
                PlatformCorridorInfo = struct([]);
            end

            % Transit Corridor
            if strcmp(app.TransitCorridorType.Value,'CSV')
                TransitCorridor = readmatrix(string(app.TransitCorridorFile.Value));
            elseif strcmp(app.TransitCorridorType.Value,'Direct')
                TransitCorridor = [PlatformConstructionPort(1,1), PlatformConstructionPort(1,2)];
            end
            dist = 0;
            if length(TransitCorridor(:,1)) >=2
                for c = 2:length(TransitCorridor(:,1))
                    dist = dist + distance(TransitCorridor(c-1,1), TransitCorridor(c-1,2), ...
                        TransitCorridor(c,1), TransitCorridor(c,2), simStep.proj);
                end
                TransitCorridorInfo = struct('rallyPoint', TransitCorridor(end, 1:2), 'dist', round(dist));
            else
                TransitCorridorInfo = struct([]);  
            end


            if app.PortsSame.Value == 1
                TowPortLocation = [PlatformConstructionPort];
                TowCorridorInfo = PlatformCorridorInfo;
                TurbineIntegrationPort = PlatformConstructionPort;
            elseif app.PortsSame.Value == 0
                TowPortLocation = [PlatformConstructionPort; TurbineIntegrationPort];
                TowCorridorInfo = struct('PlatformCorridor',PlatformCorridorInfo,'TurbineCorridor',TurbineCorridorInfo,'TransitCorridor',TransitCorridorInfo);
            end
            simStep.PortsSame     = app.PortsSame.Value; % Is turbine port the same as platform port
            if app.PortsSame.Value ==0
                simStep.timeToTurbine = inf;
                simStep.timeToPlatform = inf;
            end

            %% Error Handling
            if all(app.TowParticulars.TowLimit == 1)
                error("Error: All tow vessels are limited and will not tow fully assembled Turbines. Unlimit at least one tow vessel.")
            elseif any(app.TowParticulars.TowLimit == 1) && app.PortsSame.Value == 1
                warning("Warning: The limited tow vessels will not be usedTow vessel #1 is limited and will not be used")
            elseif app.PlatformWetStorageInit.Value + app.TurbineWetStorageInit.Value > arraySize  
                warning('Warning: More platforms/turbines than needed in wet storage. Port construction/assembly activities will not occur and extra platforms/turbines will be ignored.')
            elseif app.PortsSame.Value == 1 && app.PlatformWetStorageInit.Value > 0
                warning('Warning: The turbine integration port will be used for platform construction & turbine integration activities. Initial platforms in wet storage at the Platform Construction port will be ignored.')
                %app.PlatformWetStorageInit.Value = 0;
            elseif app.TurbineWetStorageInit.Value == app.TurbineWetStorageMax.Value 
                error("Error: Turbine wet storage can not be full at start of simulation.")
            end


            %% Agents
            % Initialize the individual classes for each agent and provide them with necessary information for them to be spun up.
            % Each variable provided to an agent is described in the architecture document.
            agents = struct( ...
                ... Weather(time, windSpd, windDir, waveHs, waveTp, waveDir)
                'Weather', Weather(wind.t, wind.Spd, wind.Dir, wave.HsTotal, wave.TpTotal, wave.DmTotal), ...
                ... Towing(name, speed, speedLookup, hourlyRate, mobilizeTime, turbineLocation, turbineCorridor, portLocations, Corridors, transitLim, towingLim, number)
                 'TowVessel1', Towing(app.TowParticulars.Name(1), app.TowParticulars.TransitSpeed(1),  [app.TowParticulars.TransitSpeed(1), app.TowParticulars.TowSpeed(1)], app.TowParticulars.Rate(1), app.TowParticulars.Mob(1), TowPortLocation, TowCorridorInfo, [app.TowParticulars.TransitWave(1); app.TowParticulars.TransitWind(1)], [app.TowParticulars.TowWave(1); app.TowParticulars.TowWind(1)],app.TowParticulars.TowLimit(1),1));
                if app.TowNum.Value >= 2
                    agents = setfield(agents, ...
                        ... Towing(name, speed, speedLookup, hourlyRate, mobilizeTime, turbineLocation, turbineCorridor, portLocations, Corridors, transitLim, towingLim, number)
                        'TowVessel2', Towing(app.TowParticulars.Name(2), app.TowParticulars.TransitSpeed(2),  [app.TowParticulars.TransitSpeed(2), app.TowParticulars.TowSpeed(2)], app.TowParticulars.Rate(2), app.TowParticulars.Mob(2), TowPortLocation, TowCorridorInfo, [app.TowParticulars.TransitWave(2); app.TowParticulars.TransitWind(2)], [app.TowParticulars.TowWave(2); app.TowParticulars.TowWind(2)],app.TowParticulars.TowLimit(2),2));
                end
                if app.TowNum.Value >= 3
                    agents = setfield(agents, ...
                        ... Towing(name, speed, speedLookup, hourlyRate, mobilizeTime, turbineLocation, turbineCorridor, portLocations, Corridors, transitLim, towingLim, number)
                        'TowVessel3', Towing(app.TowParticulars.Name(3), app.TowParticulars.TransitSpeed(3),  [app.TowParticulars.TransitSpeed(3), app.TowParticulars.TowSpeed(3)], app.TowParticulars.Rate(3), app.TowParticulars.Mob(3), TowPortLocation, TowCorridorInfo, [app.TowParticulars.TransitWave(3); app.TowParticulars.TransitWind(3)], [app.TowParticulars.TowWave(3); app.TowParticulars.TowWind(3)],app.TowParticulars.TowLimit(3),3));
                end
                if app.TowNum.Value >= 4
                    agents = setfield(agents, ...
                        ... Towing(name, speed, speedLookup, hourlyRate, mobilizeTime, turbineLocation, turbineCorridor, portLocations, Corridors, transitLim, towingLim, number)
                        'TowVessel4', Towing(app.TowParticulars.Name(4), app.TowParticulars.TransitSpeed(4),  [app.TowParticulars.TransitSpeed(4), app.TowParticulars.TowSpeed(4)], app.TowParticulars.Rate(4), app.TowParticulars.Mob(4), TowPortLocation, TowCorridorInfo, [app.TowParticulars.TransitWave(4); app.TowParticulars.TransitWind(4)], [app.TowParticulars.TowWave(4); app.TowParticulars.TowWind(4)],app.TowParticulars.TowLimit(4),4));
                end
                if app.TowNum.Value >= 5
                    agents = setfield(agents, ...
                        ... Towing(name, speed, speedLookup, hourlyRate, mobilizeTime, turbineLocation, turbineCorridor, portLocations, Corridors, transitLim, towingLim, number)
                        'TowVessel5', Towing(app.TowParticulars.Name(5), app.TowParticulars.TransitSpeed(5),  [app.TowParticulars.TransitSpeed(5), app.TowParticulars.TowSpeed(5)], app.TowParticulars.Rate(5), app.TowParticulars.Mob(5), TowPortLocation, TowCorridorInfo, [app.TowParticulars.TransitWave(5); app.TowParticulars.TransitWind(5)], [app.TowParticulars.TowWave(5); app.TowParticulars.TowWind(5)],app.TowParticulars.TowLimit(5),5));
                end
            agents = setfield(agents,...
                ... AHTS(name, speed, speedLookup, hourlyRate, mobilizeTime, startLocation, corridor, transitLim, preinstallLim, anchorLim, mooringLim, capacity)
                'AHTS', AHTS(app.AHTSVesselName.Value, app.AHTSSpeed.Value, [app.AHTSSpeed.Value app.AHTSSpeed.Value], app.AHTSRate.Value, app.AHTSMob.Value, MooringMarshalPort(1:2), MooringCorridorInfo, [app.AHTSTransitWave.Value; app.AHTSTransitWind.Value], [app.AHTSPreInstallWave.Value; app.AHTSPreInstallWind.Value], [app.AHTSAnchorWave.Value; app.AHTSAnchorWind.Value], [app.AHTSMooringWave.Value; app.AHTSMooringWind.Value], app.AHTSStorage.Value));
            agents = setfield(agents,...
                ... MooringPort(name, lat, lon, hourlyRate)
                'MooringPort', MooringPort("Mooring Port", MooringMarshalPort(1), MooringMarshalPort(2), app.MooringPortRate.Value));
            if app.PortsSame.Value == 0
                % Add Platform port agent
                agents = setfield(agents, ...
                ...PlatformPort(name, lat, lon, hourlyRate, assemblyTime, arraySize)
                'PlatformPort', PlatformPort("Platform Port", PlatformConstructionPort(1), PlatformConstructionPort(2), app.PlatformPortRate.Value, app.PlatformTime.Value, arraySize));
                agents.PlatformPort = agents.PlatformPort.SetMaxStorage(app.PlatformWetStorageMax.Value);
                agents.PlatformPort = agents.PlatformPort.InitStorage(app.PlatformWetStorageInit.Value);
            end
            agents = setfield(agents,...    
                ... TurbinePort(name, lat, lon, hourlyRate, assemblyTime, arraySize)
                'TurbinePort', TurbinePort("Turbine Port", TurbineIntegrationPort(1), TurbineIntegrationPort(2), app.TurbinePortRate.Value, app.TurbineTime.Value, arraySize));
            

            % Set some data in the ports
            agents.MooringPort = agents.MooringPort.addToStorage(app.NumLines.Value*arraySize);
            agents.TurbinePort = agents.TurbinePort.SetMaxStorage(app.TurbineWetStorageMax.Value);
            agents.TurbinePort = agents.TurbinePort.InitStorage(app.TurbineWetStorageInit.Value);
            
            %save('agents_GUI.mat', "agents")
            %% Run the simulation
            fnames = fieldnames(agents);
            done = false(size(fnames));
            iterMax = 1e5;
            iter = 0;
            weatherCounts = zeros(size(fnames));

            % iter should never approach iterMax, and in fact typically there will be an error in the weather module before 1e6
            % steps occur.
            % towInfoA = simStep.towInfo;
            % towInfoA = agents.TowVessel.status;
            while ~all(done) && iter <= iterMax
                % Step forward in time.
                simStep.time = startTime + hours(iter);
                simStep.iter = iter + 1;

                % if ~strcmp(towInfoA, agents.TowVessel.status)
                    % disp(simStep.towInfo)
                    % disp(agents.TowVessel.status)
                    % towInfoA = agents.TowVessel.status;
                % end

                % call each agent.
                for a = 1:length(fnames)
                    [agents.(fnames{a}), simStep, out] = agents.(fnames{a}).step(simStep);
                    %disp(out)
                    % keep track of weather delays.
                    if contains(out, 'delayed')
                        weatherCounts(a) = weatherCounts(a) + 1;
                    end
                    % keep track of which agents have finished operations.
                    if ~done(a) && any(agents.(fnames{a}).status ==  ["Finished", "Weather"])
                        done(a) = true;
                    end
                end
                simStep.agents = agents;

                % Bookkeeping
                iter = iter + 1;
                if iter == 1
                    simSave(iter) = struct('time', simStep.time, 'proj', simStep.proj, 'arrayConfig', simStep.array(:, [1 2 4]), 'arrayStatus', simStep.array(:,[4 3 5]), 'MooringStatus', simStep.mooringsIn(:,2:end), 'transitCorridor', MooringCorridor);
                else
                    simSave(iter).time = simStep.time;
                    simSave(iter).arrayStatus = simStep.array(:, [4 3 5]); % Array ID, status, completion time
                    simSave(iter).MooringStatus = simStep.mooringsIn(:,2:end);
                end
                for f = 1:length(fnames)
                    if agents.(fnames{f}).name == "Weather"
                        continue;
                    end
                    simSave(iter).(fnames{f}) = agents.(fnames{f});
                end
            end
            app.ResultsSummary.Value = 'Simulation Complete. Saving Results.';

            % Convert to a table (for some reason this is more matlab memory efficient) and save
            simSave = struct2table(simSave);
            save([app.ExportFileName.Value,'.mat'], "simSave")
            
            % Display total cost and time in Results Summary
            c = 0;
            for a = 1:length(fnames)
                if contains(fnames{a}, 'Weather')
                    continue;
                end
                c = c + agents.(fnames{a}).cost;
            end
            text1 = sprintf('Total cost: $%0.4fM\n', c/1000000);
            text2 = sprintf('Total time: %0.4f days\n', days(simStep.time - startTime));
            app.ResultsSummary.Value = {text1;text2};
                
            % Update results plot
            l = length(simSave.time);
            timeFromStart = 1:l;
            for i = 1:l
                statusi = simSave.arrayStatus{i,1}(:,2);
                mooringsInProgress(i) = sum(statusi==1);
                TurbinesComplete(i) = sum(statusi==3);
                mooringsComplete(i) = TurbinesComplete(i) + sum(statusi==2);
                mooringStorage(i,1) = simSave.MooringPort(i,1).storage/6;
                if app.PortsSame.Value ==0
                    PlatformStorage(i,1) = simSave.PlatformPort(i,1).storage;
                end
                TurbineStorage(i,1) = simSave.TurbinePort(i,1).storage;
            end
            daysFromStart = timeFromStart./24;
            plot(app.ResultsPlot, daysFromStart, mooringsInProgress)
            hold (app.ResultsPlot, 'on') 
            plot(app.ResultsPlot, daysFromStart, mooringsComplete)
            plot(app.ResultsPlot, daysFromStart, TurbinesComplete)
            plot(app.ResultsPlot, daysFromStart, mooringStorage)
            if app.PortsSame.Value == 0
                    plot(app.ResultsPlot, daysFromStart, PlatformStorage)
            end
            plot(app.ResultsPlot, daysFromStart, TurbineStorage)
            if app.PortsSame.Value  == 0
                    legend(app.ResultsPlot, 'Moorings in Progress', 'Moorings Complete', 'Turbines Installed','Moorings in Storage', 'Platforms in Storage','Turbines in Storage')
            else
                legend(app.ResultsPlot, 'Moorings in Progress', 'Moorings Complete', 'Turbines Installed','Moorings in Storage', 'Turbines in Storage')
            end
            hold (app.ResultsPlot, 'off') 
            
            % Update Results Table
            % Results = [WeatherDelays(days) WorkingTime(days) Costs(M$)]
            app.ResultsTable.RowName = {'Weather Delay (days)','Working Time (days)', 'Cost (M$)'};
            Tow1Idx = 2;
            Tow1Delay = round(weatherCounts(Tow1Idx)/24,1); % [days] Weather delay
            Tow1Working = round(days(agents.TowVessel1.time - startTime),1); % [days] Working time
            Tow1Cost = agents.TowVessel1.cost/1000000; % [M$]
            AHTSIdx = 2 + app.TowNum.Value;
            AHTSDelay = round(weatherCounts(AHTSIdx)/24,1); % [days] Weather delay
            AHTSWorking = round(days(agents.AHTS.time - startTime),1); % [days] Working time
            AHTSCost = agents.AHTS.cost/1000000; % [M$]
            MooringIdx = AHTSIdx + 1;
            MooringPortDelay = round(weatherCounts(MooringIdx)/24,1); % [days] Weather delay
            MooringPortWorking = round(days(agents.MooringPort.time - startTime),1); % [days] Working time
            MooringPortCost = agents.MooringPort.cost/1000000; % [M$]
            if app.PortsSame.Value == 1
                TurbineIdx = MooringIdx + 1;
                TurbinePortDelay = round(weatherCounts(TurbineIdx)/24,1); % [days] Weather delay
                TurbinePortWorking = round(days(agents.TurbinePort.time - startTime),1); % [days] Working time
                TurbinePortCost = agents.TurbinePort.cost/1000000; % [M$]
            else
                PlatformIdx = MooringIdx + 1;
                PlatformPortDelay = round(weatherCounts(PlatformIdx)/24,1); % [days] Weather delay
                PlatformPortWorking = round(days(agents.PlatformPort.time - startTime),1); % [days] Working time
                PlatformPortCost = agents.PlatformPort.cost/1000000; % [M$]
                TurbineIdx = PlatformIdx + 1;
                TurbinePortDelay = round(weatherCounts(TurbineIdx)/24,1); % [days] Weather delay
                TurbinePortWorking = round(days(agents.TurbinePort.time - startTime),1); % [days] Working time
                TurbinePortCost = agents.TurbinePort.cost/1000000; % [M$]
            end
            if app.TowNum.Value >= 2
                Tow2Idx = 3;
                Tow2Delay = round(weatherCounts(Tow2Idx)/24,1); % [days] Weather delay
                Tow2Working = round(days(agents.TowVessel2.time - startTime),1); % [days] Working time
                Tow2Cost = agents.TowVessel2.cost/1000000; % [M$]
                if app.PortsSame.Value == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                else
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                end
            end
            if app.TowNum.Value >= 3
                Tow3Idx = 4;
                Tow3Delay = round(weatherCounts(Tow3Idx)/24,1); % [days] Weather delay
                Tow3Working = round(days(agents.TowVessel3.time - startTime),1); % [days] Working time
                Tow3Cost = agents.TowVessel3.cost/1000000; % [M$]
                if app.PortsSame.Value == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'TowVessel3','AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                else
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2','TowVessel3', 'AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                end
            end
            if app.TowNum.Value >= 4
                Tow4Idx = 5;
                Tow4Delay = round(weatherCounts(Tow4Idx)/24,1); % [days] Weather delay
                Tow4Working = round(days(agents.TowVessel4.time - startTime),1); % [days] Working time
                Tow4Cost = agents.TowVessel4.cost/1000000; % [M$]
                if app.PortsSame.Value == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'TowVessel3','TowVessel4','AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[Tow4Delay;Tow4Working;Tow4Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                else
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2','TowVessel3','TowVessel4', 'AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[Tow4Delay;Tow4Working;Tow4Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                end
            end
            if app.TowNum.Value >= 5
                Tow5Idx = 6;
                Tow5Delay = round(weatherCounts(Tow5Idx)/24,1); % [days] Weather delay
                Tow5Working = round(days(agents.TowVessel5.time - startTime),1); % [days] Working time
                Tow5Cost = agents.TowVessel5.cost/1000000; % [M$]
                if app.PortsSame.Value == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'TowVessel3','TowVessel4','TowVessel5','AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[Tow4Delay;Tow4Working;Tow4Cost],[Tow5Delay;Tow5Working;Tow5Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                else
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2','TowVessel3','TowVessel4', 'TowVessel5','AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[Tow4Delay;Tow4Working;Tow4Cost],[Tow5Delay;Tow5Working;Tow5Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                end
            
            else
                if app.PortsSame.Value == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1', 'AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                else
                    app.ResultsTable.ColumnName = {'Tow Vessel1', 'AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                end
            end
            disp('Simulation Complete')
        end

        % Value changed function: StartDate
        function StartDateValueChanged(app, event)

        end

        % Value changed function: AHTSTransitWind
        function AHTSTransitWindValueChanged(app, event)

        end

        % Value changed function: AHTSTransitWave
        function AHTSTransitWaveValueChanged(app, event)

        end

        % Value changed function: AHTSMooringWind
        function AHTSMooringWindValueChanged(app, event)

        end

        % Value changed function: AHTSMooringWave
        function AHTSMooringWaveValueChanged(app, event)

        end

        % Value changed function: AHTSAnchorWind
        function AHTSAnchorWindValueChanged(app, event)

        end

        % Value changed function: AHTSAnchorWave
        function AHTSAnchorWaveValueChanged(app, event)

        end

        % Value changed function: AHTSPreInstallWind
        function AHTSPreInstallWindValueChanged(app, event)

        end

        % Value changed function: AHTSPreInstallWave
        function AHTSPreInstallWaveValueChanged(app, event)
 
        end

        % Value changed function: AHTSMob
        function AHTSMobValueChanged(app, event)

        end

        % Value changed function: AHTSRate
        function AHTSRateValueChanged(app, event)

        end

        % Value changed function: AHTSSpeed
        function AHTSSpeedValueChanged(app, event)

        end

        % Value changed function: AHTSVesselName
        function AHTSVesselNameValueChanged(app, event)

        end

        % Value changed function: TowTowWind
        function TowTowWindValueChanged(app, event)
            app.TowParticulars.TowWind(str2double(app.TowSelect.Value)) = app.TowTowWind.Value;
        end

        % Value changed function: TowTowWave
        function TowTowWaveValueChanged(app, event)
            app.TowParticulars.TowWave(str2double(app.TowSelect.Value)) = app.TowTowWave.Value;
        end

        % Value changed function: TowTransitWind
        function TowTransitWindValueChanged(app, event)
            app.TowParticulars.TransitWind(str2double(app.TowSelect.Value)) = app.TowTransitWind.Value;
        end

        % Value changed function: TowTransitWave
        function TowTransitWaveValueChanged(app, event)
            app.TowParticulars.TransitWave(str2double(app.TowSelect.Value)) = app.TowTransitWave.Value;
        end

        % Value changed function: TowMob
        function TowMobValueChanged(app, event)
            app.TowParticulars.Mob(str2double(app.TowSelect.Value)) = app.TowMob.Value;
        end

        % Value changed function: TowRate
        function TowRateValueChanged(app, event)
            app.TowParticulars.Rate(str2double(app.TowSelect.Value)) = app.TowRate.Value;
        end

        % Value changed function: TowTransitSpeed
        function TowTransitSpeedValueChanged(app, event)
            app.TowParticulars.TransitSpeed(str2double(app.TowSelect.Value)) = app.TowTransitSpeed.Value;
        end

        % Value changed function: TowVesselName
        function TowVesselNameValueChanged(app, event)
            TowValue = str2double(app.TowSelect.Value);
            app.TowParticulars.Name(TowValue) = app.TowVesselName.Value;
            if TowValue == 1
                app.Tow1CheckBox.Text = app.TowVesselName.Value;
            elseif TowValue == 2
                app.Tow2CheckBox.Text = app.TowVesselName.Value;
            elseif TowValue == 3
                app.Tow3CheckBox.Text = app.TowVesselName.Value;
            elseif TowValue == 4
                app.Tow4CheckBox.Text = app.TowVesselName.Value;
            elseif TowValue == 5
                app.Tow5CheckBox.Text = app.TowVesselName.Value;
            end

        end

        % Value changed function: ExportFileName
        function ExportFileNameValueChanged(app, event)
            app.ResultFileNameMap.Value = app.ExportFileName.Value;

        end

        % Value changed function: ResultFileNameMap
        function ResultFileNameMapValueChanged(app, event)

        end

        % Button pushed function: CreateMap
        function CreateMapButtonPushed(app, event)
            if app.AHTSCheckBox.Value == false && app.Tow1CheckBox.Value == false && app.Tow2CheckBox.Value == false && app.Tow3CheckBox.Value == false && app.Tow4CheckBox.Value == false && app.Tow5CheckBox.Value == false
                error('Error: select a vessel to plot the path')
            end
            disp('Creating Map')
            try
                load(app.ResultFileNameMap.Value);
            catch
                error('Error: Results file not loaded')     
            end
            [l a]= size(simSave);
            disp(l)
            seeNames = simSave.Properties.VariableNames;
            coTow = contains(seeNames,"TowVessel");
            nTow = sum(coTow);
            coPort = contains(seeNames,"PlatformPort");
            if sum(coPort) == 0
                portsSame = 1;
            elseif sum(coPort) == 1
                portsSame = 0;
            else
                error('Error: Multiple platform construction ports')
            end
            % Plot Mooring Port
            if sum(contains(seeNames,"MooringPort"))==1
                disp('Plotting Mooring Marshal Port')
                MorPortLat = simSave.MooringPort.lat;
                MorPortLon = simSave.MooringPort.lon;
                geoscatter(app.ResultMap,MorPortLat,MorPortLon,'filled','b');
                hold(app.ResultMap,'on')
            else 
                disp('Warning: Mooring Marshal Port missing from the results file')
            end
            % Plot Turbine Port
            if sum(contains(seeNames,"TurbinePort"))==1
                disp('Plotting Turbine Integration Port')
                TurbPortLat = simSave.TurbinePort.lat;
                TurbPortLon = simSave.TurbinePort.lon;
                geoscatter(app.ResultMap,TurbPortLat,TurbPortLon,'filled','g');
            else 
                disp('Warning: Turbine Integration Port missing from the results file')
            end
            % Plot Platform Port
            if sum(contains(seeNames,"PlatformPort"))==1
                disp('Plotting Platform Construction Port')
                PlatPortLat = simSave.PlatformPort.lat;
                PlatPortLon = simSave.PlatformPort.lon;
                geoscatter(app.ResultMap,PlatPortLat,PlatPortLon,'filled','Color',[1 0.5 0]);
            end
            % Plot Array
            if sum(contains(seeNames,"arrayConfig"))==1
                disp('Plotting Array')
                arrayLat = simSave.arrayConfig{1,1}(:,1);
                arrayLon = simSave.arrayConfig{1,1}(:,2);
                geoscatter(app.ResultMap,arrayLat,arrayLon, 'filled','k');
            else
                disp('Warning: The Array is missing from the results file')
            end
            if app.AHTSCheckBox.Value == true && sum(contains(seeNames,"AHTS"))==1
                % Plot AHTS Path
                disp('Plotting AHTS Path')
                for i = 1:l
                    AHTSStatus(i,1) = simSave.AHTS(i).status;
                    AHTSLat(i,1) = simSave.AHTS(i).lat;
                    AHTSLon(i,1) = simSave.AHTS(i).lon;
                    %geoplot(app.ResultMap,[AHTSLat(i-1) AHTSLat(i)], [AHTSLon(i-1) AHTSLon(i)],'-b');
                end
                geoplot(app.ResultMap,AHTSLat,AHTSLon,'-b');
            elseif sum(contains(seeNames,"AHTS"))==0
                disp('Warning: The AHTS is missing from the results file')
            end
            if app.Tow1CheckBox.Value == true && sum(contains(seeNames,"TowVessel1"))==1
                % Plot Tow Vessel Path
                disp('Plotting Tow 1 Path')
                for i = 1:l
                    TowStatus(i,1) = simSave.TowVessel1(i).status;
                    Tow1Lat(i,1) = simSave.TowVessel1(i).lat;
                    Tow1Lon(i,1) = simSave.TowVessel1(i).lon;
                    %geoplot(app.ResultMap,[Tow1Lat(i-1) Tow1Lat(i)], [Tow1Lon(i-1) Tow1Lon(i)],'-g');
                end
                geoplot(app.ResultMap,Tow1Lat, Tow1Lon,'-g');
            elseif sum(contains(seeNames,"TowVessel1"))==0
                disp('Warning: Tow1 is missing from the results file')
            end
            if app.Tow2CheckBox.Value == true && sum(contains(seeNames,"TowVessel2"))==1
            %    % Plot Tow Vessel Path
                disp('Plotting Tow 2 Path')
                for i = 1:l
                    TowStatus(i,1) = simSave.TowVessel2(i).status;
                    Tow2Lat(i,1) = simSave.TowVessel2(i).lat;
                    Tow2Lon(i,1) = simSave.TowVessel2(i).lon;
                    geoplot(app.ResultMap,[Tow2Lat(i-1) Tow2Lat(i)], [Tow2Lon(i-1) Tow2Lon(i)],'Color',[1 0.5 0]);
                end
            elseif app.Tow2CheckBox.Value == true && sum(contains(seeNames,"TowVessel2"))==0
                disp('Warning: Tow 2 does not exist and will not be plotted.')
            end
            if app.Tow3CheckBox.Value == true && sum(contains(seeNames,"TowVessel3"))==1
                % Plot Tow Vessel Path
                for i = 1:l
                    TowStatus(i,1) = simSave.TowVessel3(i).status;
                    Tow3Lat(i,1) = simSave.TowVessel3(i).lat;
                    Tow3Lon(i,1) = simSave.TowVessel3(i).lon;
                    geoplot(app.ResultMap,[Tow3Lat(i-1) Tow3Lat(i)], [Tow3Lon(i-1) Tow3Lon(i)],'Color',[0.5 0 0.5]);
                end
            elseif app.Tow3CheckBox.Value == true && sum(contains(seeNames,"TowVessel3"))==0
                disp('Warning: Tow 3 does not exist and will not be plotted.')
            end
            if app.Tow4CheckBox.Value == true && sum(contains(seeNames,"TowVessel4"))==1
                % Plot Tow Vessel Path
                for i = 1:l
                    TowStatus(i,1) = simSave.TowVessel4(i).status;
                    Tow4Lat(i,1) = simSave.TowVessel4(i).lat;
                    Tow4Lon(i,1) = simSave.TowVessel4(i).lon;
                    geoplot(app.ResultMap,[Tow4Lat(i-1) Tow4Lat(i)], [Tow4Lon(i-1) Tow4Lon(i)],'-r');
                end
            elseif app.Tow4CheckBox.Value == true && ~ismember(simSave, "TowVessel4")
                disp('Warning: Tow 4 does not exist and will not be plotted.')
            end
            if app.Tow5CheckBox.Value == true && sum(contains(seeNames,"TowVessel5"))==1
                % Plot Tow Vessel Path
                for i = 1:l
                    TowStatus(i,1) = simSave.TowVessel5(i).status;
                    Tow5Lat(i,1) = simSave.TowVessel5(i).lat;
                    Tow5Lon(i,1) = simSave.TowVessel5(i).lon;
                    geoplot(app.ResultMap,[Tow5Lat(i-1) Tow5Lat(i)], [Tow5Lon(i-1) Tow5Lon(i)],'Color','-c');
                end
            elseif app.Tow5CheckBox.Value == true && sum(contains(seeNames,"TowVessel5"))==0
                disp('Warning: Tow 5 does not exist and will not be plotted.')
            end
            hold(app.ResultMap,'off')
        end

        % Callback function
        function CreatePlotButtonPushed(app, event)

        end

        % Value changed function: PlatformPortDropDown
        function PlatformPortDropDownValueChanged(app, event)
            % Update Latitude and Longitude
            if strcmp(app.PlatformPortDropDown.Value,"other")
                app.PlatformLatitude.Enable = 'on';
                app.PlatformLongitude.Enable = 'on';
            else
                app.PlatformLatitude.Value = app.PortTable{char(app.PlatformPortDropDown.Value), 'Latitude'};
                app.PlatformLongitude.Value = app.PortTable{char(app.PlatformPortDropDown.Value), 'Longitude'};
                app.PlatformLatitude.Enable = 'off';
                app.PlatformLongitude.Enable = 'off';
            end
            if app.PortsSame.Value == 1
                app.TurbinePortDropDown.Value  = app.PlatformPortDropDown.Value;
                app.TurbineLatitude.Value = app.PlatformLatitude.Value;
                app.TurbineLongitude.Value = app.PlatformLongitude.Value;
                if ishandle(app.TurbineCorr)
                    delete(app.TurbineCorr);
                end
                if ishandle(app.TurbineCoord)
                    delete(app.TurbineCoord);
                end
            end 
            if ishandle(app.PlatformCorr)
                delete(app.PlatformCorr);
            end
            if ishandle(app.PlatformCoord)
                delete(app.PlatformCoord);
            end
            if ishandle(app.TransitCorr)
                delete(app.TransitCorr);
            end
        end

        % Value changed function: PortsSame
        function PortsSameValueChanged(app, event)
            if app.PortsSame.Value == 1
                app.TurbinePortDropDown.Value  = app.PlatformPortDropDown.Value;
                app.TurbineLatitude.Value = app.PlatformLatitude.Value;
                app.TurbineLongitude.Value = app.PlatformLongitude.Value;
                app.TurbineCorridorFile.Value = app.PlatformCorridorFile.Value;
                app.TurbinePortDropDown.Enable  = 'off';
                app.TurbineLatitude.Enable = 'off';
                app.TurbineLongitude.Enable = 'off';
                app.PlotTurbineCorridor.Enable = 'off';
                app.TurbineCorridorFile.Enable = 'off';
                app.TransitCorridorFile.Enable = 'off';
                app.PlotTransitCorridor.Enable = 'off';
                app.PlatformTime.Enable = 'off';
                app.PlatformWetStorageInit.Enable = 'off';
                app.PlatformWetStorageMax.Enable = 'off';
                app.PlatformPortRate.Value = 0;
                app.PlatformPortRate.Enable = 'off';
                app.TurbineCorridorType.Value = app.PlatformCorridorType.Value;
                app.TurbineCorridorType.Enable = 'off';  
                app.TransitCorridorType.Enable = 'off';
                app.LoadTurbineCorridor.Enable = 'off';
                app.LoadTransitCorridor.Enable = 'off';
                app.TowLimit.Enable = 'off';
                app.TowParticulars.TowLimit = [0; 0; 0; 0; 0];
                if ishandle(app.TurbineCorr)
                    delete(app.TurbineCorr);
                end
                if ishandle(app.TurbineCoord)
                    delete(app.TurbineCoord);
                end
                if ishandle(app.TransitCorr)
                    delete(app.TransitCorr);
                end
                if ishandle(app.PlatformCorr)
                    delete(app.PlatformCorr);
                end
                if ishandle(app.PlatformCoord)
                    delete(app.PlatformCoord);
                end
            elseif app.PortsSame.Value == 0
                app.PlotTurbineCorridor.Enable = 'on';
                app.PlotTransitCorridor.Enable = 'on';
                app.PlatformTime.Enable = 'on';
                app.PlatformWetStorageInit.Enable = 'on';
                app.PlatformWetStorageMax.Enable = 'on';
                app.PlatformPortRate.Enable = 'on';
                app.TurbineCorridorType.Enable = 'on';
                app.TransitCorridorType.Enable = 'on';
                app.TowLimit.Enable = 'on';
                if strcmp(app.TurbineCorridorType.Value,'Direct')
                    app.TurbinePortDropDown.Enable  = 'on';
                    if strcmp(app.TurbinePortDropDown.Value,"other")
                        app.TurbineLatitude.Enable = 'on';
                        app.TurbineLongitude.Enable = 'on';
                    end
                elseif strcmp(app.TurbineCorridorType.Value,'CSV')
                    app.LoadTurbineCorridor.Enable = 'on';
                end
                if strcmp(app.TransitCorridorType.Value,'CSV')
                    app.LoadTransitCorridor.Enable = 'on';
                end
            else
                error('Error: It is not recognized if the Platform Construction Port is the same as the Turbine Integration.')
            end
        end

        % Button pushed function: PlotPlatformCorridor
        function PlotPlatformCorridorButtonPushed(app, event)
            if ishandle(app.PlatformCorr)
                delete(app.PlatformCorr);
            end
            if ishandle(app.PlatformCoord)
                delete(app.PlatformCoord);
            end
            Port = [app.PlatformLatitude.Value, app.PlatformLongitude.Value];
            if strcmp(app.PlatformCorridorType.Value,'CSV')
                % Load data
                Corridor = readmatrix(app.PlatformCorridorFile.Value);
            elseif strcmp(app.PlatformCorridorType.Value,'Direct')
                if app.PortsSame.Value == 1
                    %End of corridor is at array - load array data and find muster point
                    try
                        arr = readmatrix(app.ArrayCoordinatesFile.Value);
                    catch
                        warning('ERROR: Array data needed to plot a direct corridor. Please load array coordinates.')
                    end
                    difLat = Port(1) - arr(:,6);
                    difLon = Port(2) - arr(:,7);
                    [minLat,LatIdx] = min(abs(difLat));
                    [minLon,LonIdx] = min(abs(difLon));
                    %Define Towing Corridor
                    Corridor = [Port(1), Port(2);
                        arr(LatIdx,6),arr(LonIdx,7)]; 
                elseif app.PortsSame.Value == 0
                    %End of corridor is at Turbine Port
                    Corridor = [Port(1), Port(2);
                        app.TurbineLatitude.Value,app.TurbineLongitude.Value]; 
                end
            end
            % Plot on map
            hold(app.MapAxesObject,'on')
            app.PlatformCorr = geoplot(app.MapAxesObject,Corridor(:,1),Corridor(:,2),'Color',[1 0.5 0]);
            app.PlatformCoord = geoscatter(app.MapAxesObject,Port(1),Port(2),'Color',[1 0.5 0]);
            hold(app.MapAxesObject,'off')

        end

        % Button pushed function: PlotTransitCorridor
        function PlotTransitCorridorButtonPushed(app, event)
            if ishandle(app.TransitCorr)
                delete(app.TransitCorr);
            end
            Port = [app.PlatformLatitude.Value, app.PlatformLongitude.Value];
            if strcmp(app.TransitCorridorType.Value,'CSV')
                % Load data
                Corridor = readmatrix(app.TransitCorridorFile.Value);
                Corridor = [app.PlatformLatitude.Value,app.PlatformLongitude.Value;
                    Corridor];
            elseif strcmp(app.TransitCorridorType.Value,'Direct')
                try
                    arr = readmatrix(app.ArrayCoordinatesFile.Value);
                catch
                    warning('ERROR: Array data needed to plot a direct corridor. Please load array coordinates.')
                end
                difLat = Port(1) - arr(:,6);
                difLon = Port(2) - arr(:,7);
                [minLat,LatIdx] = min(abs(difLat));
                [minLon,LonIdx] = min(abs(difLon));
                %Define Towing Corridor
                Corridor = [Port(1), Port(2);
                    arr(LatIdx,6),arr(LonIdx,7)]; 
            end
            % Plot on map
            hold(app.MapAxesObject,'on')
            app.TransitCorr = geoplot(app.MapAxesObject,Corridor(:,1),Corridor(:,2),'Color',[0.5 0 0.5]);
            hold(app.MapAxesObject,'off')
            
        end

        % Button pushed function: LoadPreviousRun
        function LoadPreviousRunButtonPushed(app, event)
            disp('Loading previous run and results')
            app.ResultsSummary.Value = 'Loading previous run results.';
            % Load
                load(app.ExportFileName.Value);
                if exist('simSave','var')
                else
                    error('Error: File not loaded')
                end
                
                seeNames = simSave.Properties.VariableNames;
                coTow = contains(seeNames,"TowVessel");
                nTow = sum(coTow);
                coPort = contains(seeNames,"PlatformPort");
                if sum(coPort) == 0
                    portsSame = 1;
                elseif sum(coPort) == 1
                    portsSame = 0;
                else
                    error('Error: Multiple platform construction ports')
                end
                if portsSame ==0
                    if nTow == 5
                        fnamesTow = {'Weather';'TowVessel1';'TowVessel2';'TowVessel3';'TowVessel4';'TowVessel5';'AHTS';'MooringPort';'PlatformPort';'TurbinePort'};
                    elseif nTow == 4
                        fnames = {'Weather';'TowVessel1';'TowVessel2';'TowVessel3';'TowVessel4';'AHTS';'MooringPort';'PlatformPort';'TurbinePort'};
                    elseif nTow == 3
                        fnames = {'Weather';'TowVessel1';'TowVessel2';'TowVessel3';'AHTS';'MooringPort';'PlatformPort';'TurbinePort'};
                    elseif nTow == 2
                        fnames = {'Weather';'TowVessel1';'TowVessel2';'AHTS';'MooringPort';'PlatformPort';'TurbinePort'};
                    elseif nTow == 1
                        fnames = {'Weather';'TowVessel1';'AHTS';'MooringPort';'PlatformPort';'TurbinePort'};
                    else 
                        error('Error: File contains less than one or more than 5 tow vessels')
                    end
                elseif portsSame == 1
                    if nTow == 5
                        fnames = {'Weather';'TowVessel1';'TowVessel2';'TowVessel3';'TowVessel4';'TowVessel5';'AHTS';'MooringPort';'TurbinePort'};
                    elseif nTow == 4
                        fnames = {'Weather';'TowVessel1';'TowVessel2';'TowVessel3';'TowVessel4';'AHTS';'MooringPort';'TurbinePort'};
                    elseif nTow == 3
                        fnames = {'Weather';'TowVessel1';'TowVessel2';'TowVessel3';'AHTS';'MooringPort';'TurbinePort'};
                    elseif nTow == 2
                        fnames = {'Weather';'TowVessel1';'TowVessel2';'AHTS';'MooringPort''TurbinePort'};
                    elseif nTow == 1
                        fnames = {'Weather';'TowVessel1';'AHTS';'MooringPort';'TurbinePort'};
                    else 
                        error('Error: File contains less than one or more than 5 tow vessels')
                    end
                else
                    error('Error: number of ports not recognized from results file')
                end

           
            % Calculate overall cost and times
            l = length(simSave.time);
            timeFromStart = 1:l;
            startTime = simSave.time(1);
            mooringsInProgress = zeros(l,1);
            TurbinesComplete = zeros(l,1);
            mooringsComplete = zeros(l,1);
            mooringStorage = zeros(l,1);
            MooringCost = zeros(l,1);
            MooringDelay = zeros(l,1);
            PlatformStorage = zeros(l,1);
            PlatformCost = zeros(l,1);
            PlatformDelay = zeros(l,1);
            TurbineStorage = zeros(l,1);
            TurbineCost = zeros(l,1);
            TurbineDelay = zeros(l,1);
            AHTSCost = zeros(l,1);
            AHTSDelay = zeros(l,1);
            TowCost = zeros(l,1);
            for i = 1:l
                statusi = simSave.arrayStatus{i,1}(:,2);
                mooringsInProgress(i) = sum(statusi==1);
                TurbinesComplete(i) = sum(statusi==3);
                mooringsComplete(i) = TurbinesComplete(i) + sum(statusi==2);
                % Mooring Port
                mooringStorage(i,1) = simSave.MooringPort(i,1).storage/6;
                MooringCost(i,1) = simSave.MooringPort(i,1).cost;
                if simSave.MooringPort(i,1).status == 'Delayed'
                    MooringDelay(i,1) = 1;
                end    
                % Platform Port
                if portsSame ==0
                    PlatformStorage(i,1) = simSave.PlatformPort(i,1).storage;
                    PlatformCost(i,1) = simSave.PlatformPort(i,1).cost;
                    if simSave.PlatformPort(i,1).status == 'Delayed'
                        PlatformDelay(i,1) = 1;
                    end
                end
                % Turbine Port
                TurbineStorage(i,1) = simSave.TurbinePort(i,1).storage;
                TurbineCost(i,1) = simSave.TurbinePort(i,1).cost;
                if simSave.TurbinePort(i,1).status == 'Delayed'
                        TurbineDelay(i,1) = 1;
                end
                % AHTS
                AHTSCost(i,1) = simSave.AHTS(i,1).cost;
                if simSave.AHTS(i,1).status == 'Delayed'
                        AHTSDelay(i,1) = 1;
                end 
                
                % Tow 1
                TowCost(i,1) = simSave.TowVessel1(i,1).cost;
                if simSave.TowVessel1(i,1).status == 'Delayed'
                        TowDelay(i,1) = 1;
                end
                % Tow 2
                if nTow ==2
                    TowCost(i,2) = simSave.TowVessel2(i,1).cost;
                    if simSave.TowVessel2(i,1).status == 'Delayed'
                        TowDelay(i,2) = 1;
                    end
                end
                % Tow 3
                if nTow ==3
                    TowCost(i,3) = simSave.TowVessel3(i,1).cost;
                    if simSave.TowVessel3(i,1).status == 'Delayed'
                        TowDelay(i,3) = 1;
                    end
                end
                % Tow 4
                if nTow ==4
                    TowCost(i,4) = simSave.TowVessel4(i,1).cost;
                    if simSave.TowVessel4(i,1).status == 'Delayed'
                        TowDelay(i,4) = 1;
                    end
                end
                % Tow 5
                if nTow ==5
                    TowCost(i,5) = simSave.TowVessel5(i,1).cost;
                    if simSave.TowVessel5(i,1).status == 'Delayed'
                        TowDelay(i,5) = 1;
                    end
                end
            end
            disp('Costs & times calculated')

            % Update results plot
            daysFromStart = timeFromStart./24;
            plot(app.ResultsPlot, daysFromStart, mooringsInProgress)
            hold (app.ResultsPlot, 'on') 
            plot(app.ResultsPlot, daysFromStart, mooringsComplete)
            plot(app.ResultsPlot, daysFromStart, TurbinesComplete)
            plot(app.ResultsPlot, daysFromStart, mooringStorage)
            plot(app.ResultsPlot, daysFromStart, TurbineStorage)
            if portsSame ==0
                    plot(app.ResultsPlot, daysFromStart, PlatformStorage)
                    legend(app.ResultsPlot, 'Moorings in Progress', 'Moorings Complete', 'Turbines Installed','Moorings in Storage', 'Platforms in Storage','Turbines in Storage')
            else
                legend(app.ResultsPlot, 'Moorings in Progress', 'Moorings Complete', 'Turbines Installed','Moorings in Storage', 'Turbines in Storage')
            end
            hold (app.ResultsPlot, 'off') 
            
            % Display Text
            c = sum(PlatformCost) + sum(TurbineCost) + sum(MooringCost)+sum(AHTSCost)+sum(TowCost);
            text1 = sprintf('Total cost: $%0.4fM\n', sum(c)/1000000);
            text2 = sprintf('Total time: %0.4f days\n', days(simSave.time(end) - simSave.time(1)));
            app.ResultsSummary.Value = {text1, text2};

            % Update Results Table
            app.ResultsTable.RowName = {'Weather Delay (days)','Working Time (days)', 'Cost (M$)'};
            Tow1Delay = round(sum(TowDelay(:,1))/24,1); % [days] Weather delay
            Tow1Working = round(days(simSave.TowVessel1(end,1).time - startTime),1); % [days] Working time
            Tow1Cost = sum(TowCost(:,1))/1000000; % [M$]
            AHTSDelay = round(sum(AHTSDelay)/24,1); % [days] Weather delay
            AHTSWorking = round(days(simSave.AHTS(end,1).time - startTime),1); % [days] Working time
            AHTSCost = sum(AHTSCost)/1000000; % [M$]
            MooringPortDelay = round(sum(MooringDelay)/24,1); % [days] Weather delay
            MooringPortWorking = round(days(simSave.MooringPort(end,1).time - startTime),1); % [days] Working time
            MooringPortCost = sum(MooringCost)/1000000; % [M$]
            TurbinePortDelay = round(sum(TurbineDelay)/24,1); % [days] Weather delay
            TurbinePortWorking = round(days(simSave.TurbinePort(end,1).time - startTime),1); % [days] Working time
            TurbinePortCost = sum(TurbineCost)/1000000; % [M$]
            PlatformPortDelay = round(sum(PlatformDelay)/24,1); % [days] Weather delay
            PlatformPortWorking = round(days(simSave.PlatformPort(end,1).time - startTime),1); % [days] Working time
            PlatformPortCost = sum(PlatformCost)/1000000; % [M$]
            if portsSame == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1', 'AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
            elseif portsSame == 0
                    app.ResultsTable.ColumnName = {'Tow Vessel1', 'AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
            end
            if nTow >= 2
                Tow2Delay = round(sum(TowDelay(:,2))/24,1); % [days] Weather delay
                Tow2Working = round(days(simSave.TowVessel2(end,1).time - startTime),1); % [days] Working timee
                Tow2Cost = sum(TowCost(:,2))/1000000; % [M$]
                if portsSame == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                elseif portsSame == 0
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                end
            end
            if nTow >= 3
                Tow3Delay = round(sum(TowDelay(:,3))/24,1); % [days] Weather delay
                Tow3Working = round(days(simSave.TowVessel3(end,1).time - startTime),1); % [days] Working time
                Tow3Cost = sum(TowCost(:,3))/1000000; % [M$]
                if portsSame == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'TowVessel3','AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                elseif portsSame == 0
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2','TowVessel3', 'AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                end
            end
            if nTow >= 4
                Tow4Delay = round(sum(TowDelay(:,4))/24,1); % [days] Weather delay
                %Tow4Working = round(days(simSave.TowVessel4(end,1).time - startTime),1); % [days] Working time
                Tow4Cost = sum(TowCost(:,4))/1000000; % [M$]
                if portsSame == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'TowVessel3','TowVessel4','AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[Tow4Delay;Tow4Working;Tow4Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                elseif portsSame == 0
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2','TowVessel3','TowVessel4', 'AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[Tow4Delay;Tow4Working;Tow4Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                end
            end
            if nTow == 5
                Tow5Delay = round(sum(TowDelay(:,5))/24,1); % [days] Weather delay
                Tow5Working = round(days(simSave.TowVessel5(end,1).time - startTime),1); % [days] Working time
                Tow5Cost = sum(TowCost(:,5))/1000000; % [M$]
                if portsSame == 1
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2', 'TowVessel3','TowVessel4','TowVessel5','AHTS', 'Mooring Port', 'TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[Tow4Delay;Tow4Working;Tow4Cost],[Tow5Delay;Tow5Working;Tow5Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                elseif portsSame == 0
                    app.ResultsTable.ColumnName = {'Tow Vessel1','TowVessel2','TowVessel3','TowVessel4', 'TowVessel5','AHTS', 'Mooring Port', 'PlatformPort','TurbinePort'};
                    app.ResultsTable.Data = table([Tow1Delay;Tow1Working;Tow1Cost], [Tow2Delay;Tow2Working;Tow2Cost],[Tow3Delay;Tow3Working;Tow3Cost],[Tow4Delay;Tow4Working;Tow4Cost],[Tow5Delay;Tow5Working;Tow5Cost],[AHTSDelay;AHTSWorking;AHTSCost], [MooringPortDelay;MooringPortWorking;MooringPortCost], [PlatformPortDelay;PlatformPortWorking;PlatformPortCost],[TurbinePortDelay;TurbinePortWorking;TurbinePortCost]);
                end
            end
            disp('Results Table Updated')
        end

        % Value changed function: TowNum
        function TowNumValueChanged(app, event)
            % CURRENT TOWTOOL VERSION ONLY SUPPORTS ONE TOW VESSEL
            if app.TowNum.Value > 1
                warning('WARNING - The current version of TOWTOOL only supports one tow vessel. Only one tow vessel will be used')
                app.TowNum.Value = 1;
                app.TowSelect.Items = {'1'};
            end
            
            if app.TowNum.Value == 1
                app.TowSelect.Items = {'1'};
            elseif app.TowNum.Value == 2
                app.TowSelect.Items = {'1', '2'};
            elseif app.TowNum.Value == 3
                app.TowSelect.Items = {'1', '2', '3'};
            elseif app.TowNum.Value == 4
                app.TowSelect.Items = {'1', '2', '3','4'};
            elseif app.TowNum.Value == 5
                app.TowSelect.Items = {'1', '2','3','4','5'};
            end
        end

        % Value changed function: TowSelect
        function TowSelectValueChanged(app, event)
            TowValue = str2double(app.TowSelect.Value);
            app.TowVesselName.Value = app.TowParticulars.Name(TowValue);
            app.TowRate.Value = app.TowParticulars.Rate(TowValue);
            app.TowMob.Value = app.TowParticulars.Mob(TowValue);
            app.TowTransitSpeed.Value = app.TowParticulars.TransitSpeed(TowValue);
            app.TowTowSpeed.Value = app.TowParticulars.TowSpeed(TowValue);
            app.TowTransitWave.Value = app.TowParticulars.TransitWave(TowValue);
            app.TowTransitWind.Value = app.TowParticulars.TransitWind(TowValue);
            app.TowTowWave.Value = app.TowParticulars.TowWave(TowValue);
            app.TowTowWind.Value = app.TowParticulars.TowWind(TowValue);
            app.TowLimit.Value = app.TowParticulars.TowLimit(TowValue);
        end

        % Value changed function: TowTowSpeed
        function TowTowSpeedValueChanged(app, event)
            app.TowParticulars.TowSpeed(str2double(app.TowSelect.Value)) = app.TowTowSpeed.Value;
        end

        % Value changed function: TowLimit
        function TowLimitValueChanged(app, event)
            app.TowParticulars.TowLimit(str2double(app.TowSelect.Value)) = app.TowLimit.Value;
        end

        % Value changed function: TurbineWetStorageInit
        function TurbineWetStorageInitValueChanged(app, event)
            
            
        end

        % Value changed function: PlatformWetStorageMax
        function PlatformWetStorageMaxValueChanged(app, event)
            app.PlatformWetStorageInit.Limits = [0, app.PlatformWetStorageMax.Value];
            
        end

        % Value changed function: TurbineWetStorageMax
        function TurbineWetStorageMaxValueChanged(app, event)
            app.TurbineWetStorageInit.Limits = [0, app.TurbineWetStorageMax.Value];
            
        end

        % Value changed function: TurbineLongitude
        function TurbineLongitudeValueChanged(app, event)
            if app.TurbineLatitude.Value == app.PlatformLatitude.Value && app.TurbineLongitude.Value == app.PlatformLongitude.Value
                app.PortsSame.Value = 1;
                app.TurbinePortDropDown.Value  = app.PlatformPortDropDown.Value;
                app.TurbineLatitude.Value = app.PlatformLatitude.Value;
                app.TurbineLongitude.Value = app.PlatformLongitude.Value;
                app.TurbineCorridorFile.Value = app.PlatformCorridorFile.Value;
                app.TurbinePortDropDown.Enable  = 'off';
                app.TurbineLatitude.Enable = 'off';
                app.TurbineLongitude.Enable = 'off';
                app.PlotTurbineCorridor.Enable = 'off';
                app.TurbineCorridorFile.Enable = 'off';
                app.TransitCorridorFile.Enable = 'off';
                app.PlotTransitCorridor.Enable = 'off';
                app.PlatformTime.Enable = 'off';
                app.PlatformWetStorageInit.Enable = 'off';
                app.PlatformWetStorageMax.Enable = 'off';
                app.PlatformPortRate.Value = 0;
                app.PlatformPortRate.Enable = 'off';
                app.TurbineCorridorType.Value = app.PlatformCorridorType.Value;
                app.TurbineCorridorType.Enable = 'off';  
                app.TransitCorridorType.Enable = 'off';
                app.LoadTurbineCorridor.Enable = 'off';
                app.LoadTransitCorridor.Enable = 'off';
                app.TowLimit.Enable = 'off';
                app.TowParticulars.TowLimit = [0; 0; 0; 0; 0];
            end
            
        end

        % Value changed function: PlatformCorridorType
        function PlatformCorridorTypeValueChanged(app, event)
            if strcmp(app.PlatformCorridorType.Value,'Direct')
                app.PlatformPortDropDown.Enable = 'on';
                app.LoadPlatformCorridor.Enable = 'off';
            elseif strcmp(app.PlatformCorridorType.Value,'CSV')
                app.PlatformPortDropDown.Enable = 'off';
                app.LoadPlatformCorridor.Enable = 'on';
            end
            if ishandle(app.PlatformCorr)
                delete(app.PlatformCorr);
            end
            %if app.PortsSame.Value == 1
            %    app.TurbineCorridorType.Value = app.PlatformCorridorType.Value;
            %end
        end

        % Button pushed function: LoadPlatformCorridor
        function LoadPlatformCorridorButtonPushed(app, event)
            try
                [Platformcorridorfile,Platformcorridorpath] = uigetfile('*.csv'); %open a csv file
            catch
                warning('ERROR: Corridor data not loaded. Ensure file type is *.csv.')
            end
            % Edit Field Box to new file name
            app.PlatformCorridorFile.Value = Platformcorridorfile;
            
            %Load File
            Corridor = readmatrix(app.PlatformCorridorFile.Value);
            %Identfy Port
            targetLat = Corridor(1,1);
            targetLon = Corridor(1,2);
            tolerance = 0.5; % half a degree threshold
            % Check if both latitude AND longitude differences are within the tolerance
            latMatch = abs(app.PortTable.Latitude - targetLat) <= tolerance;
            lonMatch = abs(app.PortTable.Longitude - targetLon) <= tolerance;
            % Find Matching Port
            matchingRow = latMatch & lonMatch;
            if sum(matchingRow) == 0
                app.PlatformPortDropDown.Value = 'other';
                app.PlatformLatitude.Enable = 'on';
                app.PlatformLongitude.Enable = 'on';
            elseif sum(matchingRow) == 1
                app.PlatformPortDropDown.Value = char(app.PortTable.Properties.RowNames(matchingRow));
                app.PlatformLatitude.Value = Corridor(1,1);
                app.PlatformLongitude.Value = Corridor(1,2);
                app.PlatformLatitude.Enable = 'off';
                app.PlatformLongitude.Enable = 'off';
            else
                warning('Warning: Error determining port. Please input port coordinates manually.')
                app.PlatformPortDropDown.Value = 'other';
                app.PlatformLatitude.Enable = 'on';
                app.PlatformLongitude.Enable = 'on';
            end
            if app.PortsSame.Value == 1
                app.TurbinePortDropDown.Value = app.PlatformPortDropDown.Value;
                app.TurbineLatitude.Value = app.PlatformLatitude.Value;
                app.TurbineLongitude.Value = app.PlatformLongitude.Value;
                app.TurbineCorridorFile.Value = app.PlatformCorridorFile.Value;
            end
            if app.PortsSame.Value == 1 && app.MooringPortSame.Value == 1
                app.MooringPortDropDown.Value = app.PlatformPortDropDown.Value;
                app.MooringLatitude.Value = app.PlatformLatitude.Value;
                app.MooringLongitude.Value = app.PlatformLongitude.Value;
                app.MooringCorridorFile.Value = app.PlatformCorridorFile.Value;
            end
            if ishandle(app.PlatformCorr)
                delete(app.PlatformCorr);
            end
            if ishandle(app.PlatformCoord)
                delete(app.PlatformCoord);
            end
            if ishandle(app.TransitCorr)
                delete(app.TransitCorr);
            end
        end

        % Button pushed function: PlotArray
        function PlotArrayButtonPushed(app, event)
            if ishandle(app.ArrayCoord)
                delete(app.ArrayCoord);
            end
            arr = readmatrix(app.ArrayCoordinatesFile.Value);
            % Plot on map
            hold(app.MapAxesObject,'on')
            app.ArrayCoord = geoscatter(app.MapAxesObject,arr(:,6),arr(:,7),"k");
            hold(app.MapAxesObject,'off')
        end

        % Value changed function: MooringLongitude
        function MooringLongitudeValueChanged(app, event)
            if app.MooringLatitude.Value == app.TurbineLatitude.Value && app.MooringLongitude.Value == app.TurbineLongitude.Value
                app.MooringPortSame.Value = 1;
                app.MooringPortDropDown.Value  = app.TurbinePortDropDown.Value;
                app.MooringLatitude.Value = app.TurbineLatitude.Value;
                app.MooringLongitude.Value = app.TurbineLongitude.Value;
                app.MooringCorridorFile.Value = app.TurbineCorridorFile.Value;
                app.MooringPortDropDown.Enable  = 'off';
                app.MooringLatitude.Enable = 'off';
                app.MooringLongitude.Enable = 'off';
                app.PlotMooringCorridor.Enable = 'off';
                app.MooringCorridorFile.Enable = 'off';
                app.MooringPortRate.Value = 0;
                app.MooringPortRate.Enable = 'off';
                app.MooringCorridorType.Enable = 'off';
                app.LoadMooringCorridor.Enable = 'off';
                if ishandle(app.MooringCorr)
                    delete(app.MooringCorr);
                end
                if ishandle(app.MooringCoord)
                    delete(app.MooringCoord);
                end
            end
        end

        % Value changed function: MooringLatitude
        function MooringLatitudeValueChanged(app, event)
            if app.MooringLatitude.Value == app.TurbineLatitude.Value && app.MooringLongitude.Value == app.TurbineLongitude.Value
                app.MooringPortSame.Value = 1;
                app.MooringPortDropDown.Value  = app.TurbinePortDropDown.Value;
                app.MooringLatitude.Value = app.TurbineLatitude.Value;
                app.MooringLongitude.Value = app.TurbineLongitude.Value;
                app.MooringCorridorFile.Value = app.TurbineCorridorFile.Value;
                app.MooringPortDropDown.Enable  = 'off';
                app.MooringLatitude.Enable = 'off';
                app.MooringLongitude.Enable = 'off';
                app.PlotMooringCorridor.Enable = 'off';
                app.MooringCorridorFile.Enable = 'off';
                app.MooringPortRate.Value = 0;
                app.MooringPortRate.Enable = 'off';
                app.MooringCorridorType.Enable = 'off';
                app.LoadMooringCorridor.Enable = 'off';
                if ishandle(app.MooringCorr)
                    delete(app.MooringCorr);
                end
                if ishandle(app.MooringCoord)
                    delete(app.MooringCoord);
                end
            end
            
        end

        % Value changed function: TurbineLatitude
        function TurbineLatitudeValueChanged(app, event)

            if app.TurbineLatitude.Value == app.PlatformLatitude.Value && app.TurbineLongitude.Value == app.PlatformLongitude.Value
                app.PortsSame.Value = 1;
                app.TurbinePortDropDown.Value  = app.PlatformPortDropDown.Value;
                app.TurbineLatitude.Value = app.PlatformLatitude.Value;
                app.TurbineLongitude.Value = app.PlatformLongitude.Value;
                app.TurbineCorridorFile.Value = app.PlatformCorridorFile.Value;
                app.TurbinePortDropDown.Enable  = 'off';
                app.TurbineLatitude.Enable = 'off';
                app.TurbineLongitude.Enable = 'off';
                app.PlotTurbineCorridor.Enable = 'off';
                app.TurbineCorridorFile.Enable = 'off';
                app.TransitCorridorFile.Enable = 'off';
                app.PlotTransitCorridor.Enable = 'off';
                app.PlatformTime.Enable = 'off';
                app.PlatformWetStorageInit.Enable = 'off';
                app.PlatformWetStorageMax.Enable = 'off';
                app.PlatformPortRate.Value = 0;
                app.PlatformPortRate.Enable = 'off';
                app.TurbineCorridorType.Value = app.PlatformCorridorType.Value;
                app.TurbineCorridorType.Enable = 'off';  
                app.TransitCorridorType.Enable = 'off';
                app.LoadTurbineCorridor.Enable = 'off';
                app.LoadTransitCorridor.Enable = 'off';
                app.TowLimit.Enable = 'off';
                app.TowParticulars.TowLimit = [0; 0; 0; 0; 0];
            end
        end

        % Button pushed function: LoadTurbineCorridor
        function LoadTurbineCorridorButtonPushed(app, event)
            try
                [Turbinecorridorfile,Turbinecorridorpath] = uigetfile('*.csv'); %open a csv file
            catch
                warning('ERROR: Corridor data not loaded. Ensure file type is *.csv.')
            end
            % Edit Field Box to new file name
            app.TurbineCorridorFile.Value = Turbinecorridorfile;
            
            %Load File
            Corridor = readmatrix(app.TurbineCorridorFile.Value);
            %Identfy Port
            targetLat = Corridor(1,1);
            targetLon = Corridor(1,2);
            tolerance = 0.5; % half a degree threshold
            % Check if both latitude AND longitude differences are within the tolerance
            latMatch = abs(app.PortTable.Latitude - targetLat) <= tolerance;
            lonMatch = abs(app.PortTable.Longitude - targetLon) <= tolerance;
            % Find Matching Port
            matchingRow = latMatch & lonMatch;
            if sum(matchingRow) == 0
                app.TurbinePortDropDown.Value = 'other';
                app.TurbineLatitude.Enable = 'on';
                app.TurbineLongitude.Enable = 'on';
            elseif sum(matchingRow) == 1
                app.TurbinePortDropDown.Value = char(app.PortTable.Properties.RowNames(matchingRow));
                app.TurbineLatitude.Value = Corridor(1,1);
                app.TurbineLongitude.Value = Corridor(1,2);
                app.TurbineLatitude.Enable = 'off';
                app.TurbineLongitude.Enable = 'off';
            else
                warning('Warning: Error determining port. Please input port coordinates manually.')
                app.TurbinePortDropDown.Value = 'other';
                app.TurbineLatitude.Enable = 'on';
                app.TurbineLongitude.Enable = 'on';
            end
            if app.MooringPortSame.Value == 1
                app.MooringPortDropDown.Value = app.TurbinePortDropDown.Value;
                app.MooringLatitude.Value = app.TurbineLatitude.Value;
                app.MooringLongitude.Value = app.TurbineLongitude.Value;
                app.MooringCorridorFile.Value = app.TurbineCorridorFile.Value;
            end
            if ishandle(app.TurbineCorr)
                delete(app.TurbineCorr);
            end
            if ishandle(app.TurbineCoord)
                delete(app.TurbineCoord);
            end
            if ishandle(app.PlatformCorr)
                delete(app.PlatformCorr);
            end
            if ishandle(app.PlatformCoord)
                delete(app.PlatformCoord);
            end
        end

        % Button pushed function: LoadMooringCorridor
        function LoadMooringCorridorButtonPushed(app, event)
            try
                [Mooringcorridorfile,Mooringcorridorpath] = uigetfile('*.csv'); %open a csv file
            catch
                warning('ERROR: Corridor data not loaded. Ensure file type is *.csv.')
            end
            % Edit Field Box to new file name
            app.MooringCorridorFile.Value = Mooringcorridorfile;
            
            %Load File
            Corridor = readmatrix(app.MooringCorridorFile.Value);
            %Identfy Port
            targetLat = Corridor(1,1);
            targetLon = Corridor(1,2);
            tolerance = 0.5; % half a degree threshold
            % Check if both latitude AND longitude differences are within the tolerance
            latMatch = abs(app.PortTable.Latitude - targetLat) <= tolerance;
            lonMatch = abs(app.PortTable.Longitude - targetLon) <= tolerance;
            % Find Matching Port
            matchingRow = latMatch & lonMatch;
            if sum(matchingRow) == 0
                app.MooringPortDropDown.Value = 'other';
                app.MooringLatitude.Enable = 'on';
                app.MooringLongitude.Enable = 'on';
            elseif sum(matchingRow) == 1
                app.MooringPortDropDown.Value = char(app.PortTable.Properties.RowNames(matchingRow));
                app.MooringLatitude.Value = Corridor(1,1);
                app.MooringLongitude.Value = Corridor(1,2);
                app.MooringLatitude.Enable = 'off';
                app.MooringLongitude.Enable = 'off';
            else
                warning('Warning: Error determining port. Please input port coordinates manually.')
                app.MooringPortDropDown.Value = 'other';
                app.MooringLatitude.Enable = 'on';
                app.MooringLongitude.Enable = 'on';
            end
            if ishandle(app.MooringCorr)
                delete(app.MooringCorr);
            end
            if ishandle(app.MooringCoord)
                delete(app.MooringCoord);
            end
        end

        % Value changed function: TurbineCorridorType
        function TurbineCorridorTypeValueChanged(app, event)
            if strcmp(app.TurbineCorridorType.Value,'Direct')
                app.TurbinePortDropDown.Enable = 'on';
                app.LoadTurbineCorridor.Enable = 'off';
            elseif strcmp(app.TurbineCorridorType.Value,'CSV')
                app.TurbinePortDropDown.Enable = 'off';
                app.LoadTurbineCorridor.Enable = 'on';
            end
            if ishandle(app.TurbineCorr)
                delete(app.TurbineCorr);
            end
        end

        % Value changed function: MooringCorridorType
        function MooringCorridorTypeValueChanged(app, event)
            if strcmp(app.MooringCorridorType.Value,'Direct')
                app.MooringPortDropDown.Enable = 'on';
                app.LoadMooringCorridor.Enable = 'off';
            elseif strcmp(app.MooringCorridorType.Value,'CSV')
                app.MooringPortDropDown.Enable = 'off';
                app.LoadMooringCorridor.Enable = 'on';
            end
            if ishandle(app.MooringCorr)
                delete(app.MooringCorr);
            end
        end

        % Value changed function: TransitCorridorType
        function TransitCorridorTypeValueChanged(app, event)
            if strcmp(app.TransitCorridorType.Value,'Direct')
                app.LoadTransitCorridor.Enable = 'off';
            elseif strcmp(app.TransitCorridorType.Value,'CSV')
                app.LoadTransitCorridor.Enable = 'on';
            end
            if ishandle(app.TransitCorr)
                delete(app.TransitCorr)
            end
        end

        % Button pushed function: LoadTransitCorridor
        function LoadTransitCorridorButtonPushed(app, event)
            try
                [Transitcorridorfile,Transitcorridorpath] = uigetfile('*.csv'); %open a csv file
            catch
                warning('ERROR: Corridor data not loaded. Ensure file type is *.csv.')
            end
            % Edit Field Box to new file name
            app.TransitCorridorFile.Value = Transitcorridorfile;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Primary Color Logo 2026.png');
            app.TOWTOOLv10Label = uilabel(app.AboutTab);
            app.TOWTOOLv10Label.Text = 'TOWTOOL v1.0';
            app.Disclaimer = uilabel(app.AboutTab);
            app.Disclaimer.Text = 'This software is for informational research purposes only  ';
            app.AssumptionsDescription = uilabel(app.AboutTab);
            app.AssumptionsDescription.Text = {'- Port & ship operations occur on a 24/7 schedule'; '- Moorings are pre-laid before turbine arrives'; '- Installation times are pre-set and depend on water depth'; '- Hindcast weather data is used to predict weather delays. Real weather on the ocean can change in minutes and is not reflected here'; '- All tow vessels start in the platform construction port'; ''};
            app.TowOutWorkflowToolkitforOffshoreOperationsandLogisticsLabel = uilabel(app.AboutTab);
            app.TowOutWorkflowToolkitforOffshoreOperationsandLogisticsLabel.Text = 'Tow-Out Workflow Toolkit for Offshore Operations and Logistics';
            app.QuickStartDescription = uilabel(app.AboutTab);
            app.QuickStartDescription.Text = {'1. Load the weather data in the "Weather" tab.'; '2. Define the port locations and vessel corridors in the  "Locations" tabs. Assume direct corridors or import a *.csv with corridor coordinates.'; '2. Update the information on the "Vessels" & "Project Details" tabs if needed.'; '3. Press "Run Simulation" on the "Run & Results" tab.'; '4. Use the "Postprocessing" tab to load previous results and create figures.'};
            app.QuickStartLabel = uilabel(app.AboutTab);
            app.QuickStartLabel.Text = 'Quick Start Guide';
            app.AssumptionsLabel = uilabel(app.AboutTab);
            app.AssumptionsLabel.Text = 'Assumptions';
            app.Contact = uilabel(app.AboutTab);
            app.Contact.Text = 'Contact: info@composites.maine.edu';
            app.Toolboxes = uilabel(app.AboutTab);
            app.Toolboxes.Text = 'Toolboxes Needed: Mapping Toolbox';
            app.Image2 = uiimage(app.AboutTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UMaine Primary Color.png');
            app.WeatherTab = uitab(app.TabGroup);
            app.WeatherTab.Title = 'Weather';
            app.WaveHeightPlot = uiaxes(app.WeatherTab);
            title(app.WaveHeightPlot, 'Wave History')
            xlabel(app.WaveHeightPlot, 'Time')
            ylabel(app.WaveHeightPlot, 'Wind Speed (m/s)')
            zlabel(app.WaveHeightPlot, 'Z')
            app.WindSpeedPlot = uiaxes(app.WeatherTab);
            title(app.WindSpeedPlot, 'Wind History')
            xlabel(app.WindSpeedPlot, 'Time')
            ylabel(app.WindSpeedPlot, 'Significant Wave Height (m)')
            zlabel(app.WindSpeedPlot, 'Z')
            app.StartDateDatePickerLabel = uilabel(app.WeatherTab);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDate = uidatepicker(app.WeatherTab);
            app.StartDate.ValueChangedFcn = createCallbackFcn(app, @StartDateValueChanged, true);
            app.StartDate.Value = datetime([1949 4 1]);
            app.WeatherData = uibutton(app.WeatherTab, 'push');
            app.WeatherData.ButtonPushedFcn = createCallbackFcn(app, @WeatherDataButtonPushed, true);
            app.WeatherData.Text = 'Load Weather Data';
            app.WeatherDataEditFieldLabel = uilabel(app.WeatherTab);
            app.WeatherDataEditFieldLabel.Text = 'WeatherData';
            app.WeatherDataEditField = uieditfield(app.WeatherTab, 'text');
            app.WeatherDataEditField.Value = 'WeatherData.csv';
            app.WindDirectionPanel = uipanel(app.WeatherTab);
            app.WindDirectionPanel.Title = 'Wind Direction';
            app.WaveDirectionPanel = uipanel(app.WeatherTab);
            app.WaveDirectionPanel.Title = 'Wave Direction';
            app.WeatherLabel2 = uilabel(app.WeatherTab);
            app.WeatherLabel2.Text = 'TOWTOOL uses hindcast weather data to estimate weather delays for port and ship operations.  ';
            app.WeatherLabel1 = uilabel(app.WeatherTab);
            app.WeatherLabel1.Text = 'Weather Data';
            app.WeatherLabel3 = uilabel(app.WeatherTab);
            app.WeatherLabel3.Text = 'Load the hindcast weather data as a *.csv file using the "Load Weather Data" button. Column names should be "t", "Significant Wave Height (m)", "Peak Wave Period (s)", "Wave Direction (deg)", "Wind Speed (m/s)", and "Wind Direction (deg)"';
            app.Image3 = uiimage(app.WeatherTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Primary Color Logo 2026.png');
            app.LocationsTab = uitab(app.TabGroup);
            app.LocationsTab.Title = 'Locations';
            app.MooringMarshalPortPanel = uipanel(app.LocationsTab);
            app.MooringMarshalPortPanel.Title = 'Mooring Marshal Port';
            app.MooringCorridorLabel = uilabel(app.MooringMarshalPortPanel);
            app.MooringCorridorLabel.Enable = 'off';
            app.MooringCorridorLabel.Text = 'File';
            app.MooringCorridorFile = uieditfield(app.MooringMarshalPortPanel, 'text');
            app.MooringCorridorFile.Enable = 'off';
            app.MooringCorridorFile.Value = 'Corridor_Humboldt_toFarm.csv';
            app.PlotMooringCorridor = uibutton(app.MooringMarshalPortPanel, 'push');
            app.PlotMooringCorridor.ButtonPushedFcn = createCallbackFcn(app, @PlotMooringCorridorPushed, true);
            app.PlotMooringCorridor.Text = 'Plot Mooring Corridor';
            app.LatLabel_3 = uilabel(app.MooringMarshalPortPanel);
            app.LatLabel_3.Enable = 'off';
            app.LatLabel_3.Text = 'Lat.';
            app.MooringLatitude = uieditfield(app.MooringMarshalPortPanel, 'numeric');
            app.MooringLatitude.Limits = [-90 90];
            app.MooringLatitude.ValueChangedFcn = createCallbackFcn(app, @MooringLatitudeValueChanged, true);
            app.MooringLatitude.Enable = 'off';
            app.MooringLatitude.Value = 40.807565;
            app.LonLabel_3 = uilabel(app.MooringMarshalPortPanel);
            app.LonLabel_3.Enable = 'off';
            app.LonLabel_3.Text = 'Lon.';
            app.MooringLongitude = uieditfield(app.MooringMarshalPortPanel, 'numeric');
            app.MooringLongitude.Limits = [-180 180];
            app.MooringLongitude.ValueChangedFcn = createCallbackFcn(app, @MooringLongitudeValueChanged, true);
            app.MooringLongitude.Enable = 'off';
            app.MooringLongitude.Value = -124.162268;
            app.TurbinePortLabel_2 = uilabel(app.MooringMarshalPortPanel);
            app.TurbinePortLabel_2.Text = 'Port';
            app.MooringPortDropDown = uidropdown(app.MooringMarshalPortPanel);
            app.MooringPortDropDown.Items = {'humboldt', 'sanfran', 'longbeach', 'searsport', 'lanouvelle', 'nigg', 'montrose', 'aberdeen', 'talbot', 'other'};
            app.MooringPortDropDown.ValueChangedFcn = createCallbackFcn(app, @MooringPortDropDownValueChanged, true);
            app.MooringPortDropDown.Value = 'humboldt';
            app.MooringPortSame = uicheckbox(app.MooringMarshalPortPanel);
            app.MooringPortSame.ValueChangedFcn = createCallbackFcn(app, @MooringPortSameValueChanged, true);
            app.MooringPortSame.Text = 'Mooring Port same as Turbine Port';
            app.SelectCorridorLabel_3 = uilabel(app.MooringMarshalPortPanel);
            app.SelectCorridorLabel_3.Text = 'Corridor Type';
            app.MooringCorridorType = uidropdown(app.MooringMarshalPortPanel);
            app.MooringCorridorType.Items = {'Direct', 'CSV'};
            app.MooringCorridorType.ValueChangedFcn = createCallbackFcn(app, @MooringCorridorTypeValueChanged, true);
            app.MooringCorridorType.Value = 'Direct';
            app.LoadMooringCorridor = uibutton(app.MooringMarshalPortPanel, 'push');
            app.LoadMooringCorridor.ButtonPushedFcn = createCallbackFcn(app, @LoadMooringCorridorButtonPushed, true);
            app.LoadMooringCorridor.Enable = 'off';
            app.LoadMooringCorridor.Text = 'Load Mooring Corridor';
            app.TurbinePortPanel = uipanel(app.LocationsTab);
            app.TurbinePortPanel.Title = 'Turbine Integration Port';
            app.TurbinePortLabel = uilabel(app.TurbinePortPanel);
            app.TurbinePortLabel.Text = 'Port';
            app.TurbinePortDropDown = uidropdown(app.TurbinePortPanel);
            app.TurbinePortDropDown.Items = {'humboldt', 'sanfran', 'longbeach', 'searsport', 'lanouvelle', 'nigg', 'montrose', 'talbot', 'other'};
            app.TurbinePortDropDown.ValueChangedFcn = createCallbackFcn(app, @TurbinePortDropDownValueChanged, true);
            app.TurbinePortDropDown.Value = 'sanfran';
            app.TurbineCorridorLabel = uilabel(app.TurbinePortPanel);
            app.TurbineCorridorLabel.Enable = 'off';
            app.TurbineCorridorLabel.Text = 'File';
            app.TurbineCorridorFile = uieditfield(app.TurbinePortPanel, 'text');
            app.TurbineCorridorFile.Enable = 'off';
            app.TurbineCorridorFile.Value = 'Corridor_SanFran_toFarm.csv';
            app.PlotTurbineCorridor = uibutton(app.TurbinePortPanel, 'push');
            app.PlotTurbineCorridor.ButtonPushedFcn = createCallbackFcn(app, @PlotTurbineCorridorButtonPushed, true);
            app.PlotTurbineCorridor.Text = 'Plot Turbine Corridor';
            app.LatLabel_2 = uilabel(app.TurbinePortPanel);
            app.LatLabel_2.Enable = 'off';
            app.LatLabel_2.Text = 'Lat.';
            app.TurbineLatitude = uieditfield(app.TurbinePortPanel, 'numeric');
            app.TurbineLatitude.Limits = [-90 90];
            app.TurbineLatitude.ValueChangedFcn = createCallbackFcn(app, @TurbineLatitudeValueChanged, true);
            app.TurbineLatitude.Enable = 'off';
            app.TurbineLatitude.Value = 37.794402;
            app.LonLabel_2 = uilabel(app.TurbinePortPanel);
            app.LonLabel_2.Enable = 'off';
            app.LonLabel_2.Text = 'Lon.';
            app.TurbineLongitude = uieditfield(app.TurbinePortPanel, 'numeric');
            app.TurbineLongitude.Limits = [-180 180];
            app.TurbineLongitude.ValueChangedFcn = createCallbackFcn(app, @TurbineLongitudeValueChanged, true);
            app.TurbineLongitude.Enable = 'off';
            app.TurbineLongitude.Value = -122.391938;
            app.PortsSame = uicheckbox(app.TurbinePortPanel);
            app.PortsSame.ValueChangedFcn = createCallbackFcn(app, @PortsSameValueChanged, true);
            app.PortsSame.Text = 'Turbine Port same as Platform Port';
            app.SelectCorridorLabel_2 = uilabel(app.TurbinePortPanel);
            app.SelectCorridorLabel_2.Text = 'Corridor Type';
            app.TurbineCorridorType = uidropdown(app.TurbinePortPanel);
            app.TurbineCorridorType.Items = {'Direct', 'CSV'};
            app.TurbineCorridorType.ValueChangedFcn = createCallbackFcn(app, @TurbineCorridorTypeValueChanged, true);
            app.TurbineCorridorType.Value = 'Direct';
            app.LoadTurbineCorridor = uibutton(app.TurbinePortPanel, 'push');
            app.LoadTurbineCorridor.ButtonPushedFcn = createCallbackFcn(app, @LoadTurbineCorridorButtonPushed, true);
            app.LoadTurbineCorridor.Enable = 'off';
            app.LoadTurbineCorridor.Text = 'Load Turbine Corridor';
            app.PlatformPortPanel = uipanel(app.LocationsTab);
            app.PlatformPortPanel.Title = 'Platform Construction Port';
            app.LatLabel = uilabel(app.PlatformPortPanel);
            app.LatLabel.Enable = 'off';
            app.LatLabel.Text = 'Lat.';
            app.PlatformLatitude = uieditfield(app.PlatformPortPanel, 'numeric');
            app.PlatformLatitude.Limits = [-90 90];
            app.PlatformLatitude.Enable = 'off';
            app.PlatformLatitude.Value = 33.739737;
            app.LonLabel = uilabel(app.PlatformPortPanel);
            app.LonLabel.Enable = 'off';
            app.LonLabel.Text = 'Lon,';
            app.PlatformLongitude = uieditfield(app.PlatformPortPanel, 'numeric');
            app.PlatformLongitude.Limits = [-180 180];
            app.PlatformLongitude.Enable = 'off';
            app.PlatformLongitude.Value = -118.205367;
            app.PortDropDownLabel = uilabel(app.PlatformPortPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PlatformPortDropDown = uidropdown(app.PlatformPortPanel);
            app.PlatformPortDropDown.Items = {'humboldt', 'sanfran', 'longbeach', 'searsport', 'lanouvelle', 'nigg', 'montrose', 'talbot', 'other'};
            app.PlatformPortDropDown.ValueChangedFcn = createCallbackFcn(app, @PlatformPortDropDownValueChanged, true);
            app.PlatformPortDropDown.Value = 'longbeach';
            app.PlotPlatformCorridor = uibutton(app.PlatformPortPanel, 'push');
            app.PlotPlatformCorridor.ButtonPushedFcn = createCallbackFcn(app, @PlotPlatformCorridorButtonPushed, true);
            app.PlotPlatformCorridor.Text = 'Plot Platform Corridor';
            app.PlatformCorridorLabel = uilabel(app.PlatformPortPanel);
            app.PlatformCorridorLabel.Enable = 'off';
            app.PlatformCorridorLabel.Text = 'File';
            app.PlatformCorridorFile = uieditfield(app.PlatformPortPanel, 'text');
            app.PlatformCorridorFile.Enable = 'off';
            app.PlatformCorridorFile.Value = 'Corridor_LongBeach_toSanFran.csv';
            app.SelectCorridorLabel = uilabel(app.PlatformPortPanel);
            app.SelectCorridorLabel.Text = 'Corridor Type';
            app.PlatformCorridorType = uidropdown(app.PlatformPortPanel);
            app.PlatformCorridorType.Items = {'Direct', 'CSV'};
            app.PlatformCorridorType.ValueChangedFcn = createCallbackFcn(app, @PlatformCorridorTypeValueChanged, true);
            app.PlatformCorridorType.Value = 'Direct';
            app.LoadPlatformCorridor = uibutton(app.PlatformPortPanel, 'push');
            app.LoadPlatformCorridor.ButtonPushedFcn = createCallbackFcn(app, @LoadPlatformCorridorButtonPushed, true);
            app.LoadPlatformCorridor.Enable = 'off';
            app.LoadPlatformCorridor.Text = 'Load Platform Corridor';
            app.ArrayLocationsPanel = uipanel(app.LocationsTab);
            app.ArrayLocationsPanel.Title = 'Array Locations';
            app.LoadArrayCoordinates = uibutton(app.ArrayLocationsPanel, 'push');
            app.LoadArrayCoordinates.ButtonPushedFcn = createCallbackFcn(app, @LoadArrayCoordinatesButtonPushed, true);
            app.LoadArrayCoordinates.Text = 'Load Array Coordinates';
            app.FileEditFieldLabel = uilabel(app.ArrayLocationsPanel);
            app.FileEditFieldLabel.Text = 'File';
            app.ArrayCoordinatesFile = uieditfield(app.ArrayLocationsPanel, 'text');
            app.ArrayCoordinatesFile.Editable = 'off';
            app.ArrayCoordinatesFile.Value = 'HumboldtArray10.csv';
            app.PlotArray = uibutton(app.ArrayLocationsPanel, 'push');
            app.PlotArray.ButtonPushedFcn = createCallbackFcn(app, @PlotArrayButtonPushed, true);
            app.PlotArray.Text = 'Plot Array Coordiantes';
            app.MapPanel = uipanel(app.LocationsTab);
            app.MapPanel.Title = 'Map';
            app.TransitCorridorPanel = uipanel(app.LocationsTab);
            app.TransitCorridorPanel.Title = 'Transit Corridor';
            app.PlotTransitCorridor = uibutton(app.TransitCorridorPanel, 'push');
            app.PlotTransitCorridor.ButtonPushedFcn = createCallbackFcn(app, @PlotTransitCorridorButtonPushed, true);
            app.PlotTransitCorridor.Text = 'Plot Transit Corridor';
            app.CorridorLabel = uilabel(app.TransitCorridorPanel);
            app.CorridorLabel.Enable = 'off';
            app.CorridorLabel.Text = 'File';
            app.TransitCorridorFile = uieditfield(app.TransitCorridorPanel, 'text');
            app.TransitCorridorFile.Enable = 'off';
            app.TransitCorridorFile.Value = 'Corridor_LongBeach_toFarm.csv';
            app.TransitCorridorNeededLabel = uilabel(app.TransitCorridorPanel);
            app.TransitCorridorNeededLabel.Text = 'If platform & turbine port are different, input a corridor between the array and the platform port for the towing vessel to transit';
            app.SelectCorridorLabel_4 = uilabel(app.TransitCorridorPanel);
            app.SelectCorridorLabel_4.Text = 'Corridor Type';
            app.TransitCorridorType = uidropdown(app.TransitCorridorPanel);
            app.TransitCorridorType.Items = {'Direct', 'CSV'};
            app.TransitCorridorType.ValueChangedFcn = createCallbackFcn(app, @TransitCorridorTypeValueChanged, true);
            app.TransitCorridorType.Value = 'Direct';
            app.LoadTransitCorridor = uibutton(app.TransitCorridorPanel, 'push');
            app.LoadTransitCorridor.ButtonPushedFcn = createCallbackFcn(app, @LoadTransitCorridorButtonPushed, true);
            app.LoadTransitCorridor.Enable = 'off';
            app.LoadTransitCorridor.Text = 'Load Transit Corridor';
            app.LocationsLabel1 = uilabel(app.LocationsTab);
            app.LocationsLabel1.Text = 'Array & Port Location Data';
            app.LocationsLabel2 = uilabel(app.LocationsTab);
            app.LocationsLabel2.Text = {'Load the array corridinates as a *.csv file containing the depth (column 3), latitude (column 6), and longitude (column 7), as exported from GIS. TOWTOOL uses the number of cooridnates to determine the array size.'; 'Define the ports and corridors by selecting the corridor type. A direct corridor will go directly from the selected port to the array. A CSV corridor is defined by a *.csv file containing coordinates and imported using "Load Corridor". The port will be defined as the first coordinates of the *.csv.'};
            app.Image3_2 = uiimage(app.LocationsTab);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'Primary Color Logo 2026.png');
            app.Vessels = uitab(app.TabGroup);
            app.Vessels.Title = 'Vessels';
            app.TowVesselsPanel = uipanel(app.Vessels);
            app.TowVesselsPanel.Title = 'Tow Vessel(s)';
            app.TowVessel1Panel = uipanel(app.TowVesselsPanel);
            app.TowVessel1Panel.Title = 'Tow Vessel 1';
            app.LimitedwonttowanassembledturbineLabel = uilabel(app.TowVessel1Panel);
            app.LimitedwonttowanassembledturbineLabel.Text = 'Limited (won''t tow assembled turbine)';
            app.TowLimit = uidropdown(app.TowVessel1Panel);
            app.TowLimit.Items = {'Yes', 'No'};
            app.TowLimit.ValueChangedFcn = createCallbackFcn(app, @TowLimitValueChanged, true);
            app.TowLimit.Value = 'No';
            app.TowingWindSpeedLimitmsEditFieldLabel = uilabel(app.TowVessel1Panel);
            app.TowingWindSpeedLimitmsEditFieldLabel.Text = 'Towing Wind Speed Limit (m/s)';
            app.TowTowWind = uieditfield(app.TowVessel1Panel, 'numeric');
            app.TowTowWind.Limits = [0 Inf];
            app.TowTowWind.ValueChangedFcn = createCallbackFcn(app, @TowTowWindValueChanged, true);
            app.TowTowWind.Value = 15;
            app.TowingWaveHeightLimitmEditFieldLabel = uilabel(app.TowVessel1Panel);
            app.TowingWaveHeightLimitmEditFieldLabel.Text = 'Towing Wave Height Limit (m)';
            app.TowTowWave = uieditfield(app.TowVessel1Panel, 'numeric');
            app.TowTowWave.Limits = [0 Inf];
            app.TowTowWave.ValueChangedFcn = createCallbackFcn(app, @TowTowWaveValueChanged, true);
            app.TowTowWave.Value = 4;
            app.TransitWindSpeedLimitmsEditFieldLabel = uilabel(app.TowVessel1Panel);
            app.TransitWindSpeedLimitmsEditFieldLabel.Text = 'Transit Wind Speed Limit (m/s)';
            app.TowTransitWind = uieditfield(app.TowVessel1Panel, 'numeric');
            app.TowTransitWind.Limits = [0 Inf];
            app.TowTransitWind.ValueChangedFcn = createCallbackFcn(app, @TowTransitWindValueChanged, true);
            app.TowTransitWind.Value = 25;
            app.TransitWaveHeightLimitmEditFieldLabel = uilabel(app.TowVessel1Panel);
            app.TransitWaveHeightLimitmEditFieldLabel.Text = 'Transit Wave Height Limit (m)';
            app.TowTransitWave = uieditfield(app.TowVessel1Panel, 'numeric');
            app.TowTransitWave.Limits = [0 Inf];
            app.TowTransitWave.ValueChangedFcn = createCallbackFcn(app, @TowTransitWaveValueChanged, true);
            app.TowTransitWave.Value = 4.75;
            app.SpeedkmhEditField_2Label_2 = uilabel(app.TowVessel1Panel);
            app.SpeedkmhEditField_2Label_2.Text = 'Towing Speed (km/h)';
            app.TowTowSpeed = uieditfield(app.TowVessel1Panel, 'numeric');
            app.TowTowSpeed.Limits = [0 Inf];
            app.TowTowSpeed.ValueChangedFcn = createCallbackFcn(app, @TowTowSpeedValueChanged, true);
            app.TowTowSpeed.Value = 9;
            app.TransitSpeedkmhLabel = uilabel(app.TowVessel1Panel);
            app.TransitSpeedkmhLabel.Text = 'Transit Speed (km/h)';
            app.TowTransitSpeed = uieditfield(app.TowVessel1Panel, 'numeric');
            app.TowTransitSpeed.Limits = [0 Inf];
            app.TowTransitSpeed.ValueChangedFcn = createCallbackFcn(app, @TowTransitSpeedValueChanged, true);
            app.TowTransitSpeed.Value = 14;
            app.MobilizeTimehrEditFieldLabel = uilabel(app.TowVessel1Panel);
            app.MobilizeTimehrEditFieldLabel.Text = 'Mobilize Time (hr)';
            app.TowMob = uieditfield(app.TowVessel1Panel, 'numeric');
            app.TowMob.Limits = [0 Inf];
            app.TowMob.ValueChangedFcn = createCallbackFcn(app, @TowMobValueChanged, true);
            app.TowMob.Value = 168;
            app.HourlyRateLabel = uilabel(app.TowVessel1Panel);
            app.HourlyRateLabel.Text = 'Hourly Rate ($)';
            app.TowRate = uieditfield(app.TowVessel1Panel, 'numeric');
            app.TowRate.Limits = [0 Inf];
            app.TowRate.ValueChangedFcn = createCallbackFcn(app, @TowRateValueChanged, true);
            app.TowRate.Value = 1400;
            app.TowVesselNameEditFieldLabel = uilabel(app.TowVessel1Panel);
            app.TowVesselNameEditFieldLabel.Text = 'Tow Vessel Name';
            app.TowVesselName = uieditfield(app.TowVessel1Panel, 'text');
            app.TowVesselName.ValueChangedFcn = createCallbackFcn(app, @TowVesselNameValueChanged, true);
            app.TowVesselName.Value = 'Tow1';
            app.NumberofTowVesselsLabel = uilabel(app.TowVesselsPanel);
            app.NumberofTowVesselsLabel.Text = 'Number of Tow Vessels';
            app.TowNum = uispinner(app.TowVesselsPanel);
            app.TowNum.Limits = [1 5];
            app.TowNum.ValueChangedFcn = createCallbackFcn(app, @TowNumValueChanged, true);
            app.TowNum.Value = 1;
            app.SelectTowVesselNumberDropDownLabel = uilabel(app.TowVesselsPanel);
            app.SelectTowVesselNumberDropDownLabel.Text = 'Select Tow Vessel Number';
            app.TowSelect = uidropdown(app.TowVesselsPanel);
            app.TowSelect.Items = {'1'};
            app.TowSelect.ValueChangedFcn = createCallbackFcn(app, @TowSelectValueChanged, true);
            app.TowSelect.Value = '1';
            app.AHTSVesselPanel = uipanel(app.Vessels);
            app.AHTSVesselPanel.Title = 'AHTS Vessel';
            app.MobilizeTimehrEditField_2Label = uilabel(app.AHTSVesselPanel);
            app.MobilizeTimehrEditField_2Label.Text = 'Mobilize Time (hr)';
            app.AHTSMob = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSMob.Limits = [0 Inf];
            app.AHTSMob.ValueChangedFcn = createCallbackFcn(app, @AHTSMobValueChanged, true);
            app.AHTSMob.Value = 168;
            app.AHTSVesselNameEditFieldLabel = uilabel(app.AHTSVesselPanel);
            app.AHTSVesselNameEditFieldLabel.Text = 'AHTS Vessel Name';
            app.AHTSVesselName = uieditfield(app.AHTSVesselPanel, 'text');
            app.AHTSVesselName.ValueChangedFcn = createCallbackFcn(app, @AHTSVesselNameValueChanged, true);
            app.AHTSVesselName.Value = 'AHTS';
            app.SpeedkmhEditFieldLabel = uilabel(app.AHTSVesselPanel);
            app.SpeedkmhEditFieldLabel.Text = 'Speed (km/h)';
            app.AHTSSpeed = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSSpeed.Limits = [0 Inf];
            app.AHTSSpeed.ValueChangedFcn = createCallbackFcn(app, @AHTSSpeedValueChanged, true);
            app.AHTSSpeed.Value = 14;
            app.HourlyRateEditField_2Label = uilabel(app.AHTSVesselPanel);
            app.HourlyRateEditField_2Label.Text = 'Hourly Rate ($)';
            app.AHTSRate = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSRate.Limits = [0 Inf];
            app.AHTSRate.ValueChangedFcn = createCallbackFcn(app, @AHTSRateValueChanged, true);
            app.AHTSRate.Value = 4000;
            app.PreInstallWaveHeightLimitmEditFieldLabel = uilabel(app.AHTSVesselPanel);
            app.PreInstallWaveHeightLimitmEditFieldLabel.Text = 'Pre-Install Wave Height Limit (m)';
            app.AHTSPreInstallWave = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSPreInstallWave.Limits = [0 Inf];
            app.AHTSPreInstallWave.ValueChangedFcn = createCallbackFcn(app, @AHTSPreInstallWaveValueChanged, true);
            app.AHTSPreInstallWave.Value = 4.3;
            app.MooringInstallWaveHeightLimitmEditFieldLabel = uilabel(app.AHTSVesselPanel);
            app.MooringInstallWaveHeightLimitmEditFieldLabel.Text = 'Mooring Install Wave Height Limit (m)';
            app.AHTSMooringWave = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSMooringWave.Limits = [0 Inf];
            app.AHTSMooringWave.ValueChangedFcn = createCallbackFcn(app, @AHTSMooringWaveValueChanged, true);
            app.AHTSMooringWave.Value = 4;
            app.AnchorInstallWindSpeedLimitmsEditFieldLabel = uilabel(app.AHTSVesselPanel);
            app.AnchorInstallWindSpeedLimitmsEditFieldLabel.Text = 'Anchor Install Wind Speed Limit (m/s)';
            app.AHTSAnchorWind = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSAnchorWind.Limits = [0 Inf];
            app.AHTSAnchorWind.ValueChangedFcn = createCallbackFcn(app, @AHTSAnchorWindValueChanged, true);
            app.AHTSAnchorWind.Value = 20;
            app.AnchorInstallWaveHeightLimitmEditFieldLabel = uilabel(app.AHTSVesselPanel);
            app.AnchorInstallWaveHeightLimitmEditFieldLabel.Text = 'Anchor Install Wave Height Limit (m)';
            app.AHTSAnchorWave = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSAnchorWave.Limits = [0 Inf];
            app.AHTSAnchorWave.ValueChangedFcn = createCallbackFcn(app, @AHTSAnchorWaveValueChanged, true);
            app.AHTSAnchorWave.Value = 4.1;
            app.PreInstallWindSpeedLimitmsEditFieldLabel = uilabel(app.AHTSVesselPanel);
            app.PreInstallWindSpeedLimitmsEditFieldLabel.Text = 'Pre-Install Wind Speed Limit (m/s)';
            app.AHTSPreInstallWind = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSPreInstallWind.Limits = [0 Inf];
            app.AHTSPreInstallWind.ValueChangedFcn = createCallbackFcn(app, @AHTSPreInstallWindValueChanged, true);
            app.AHTSPreInstallWind.Value = 20;
            app.TransitWindSpeedLimitmsEditField_2Label = uilabel(app.AHTSVesselPanel);
            app.TransitWindSpeedLimitmsEditField_2Label.Text = 'Transit Wind Speed Limit (m/s)';
            app.AHTSTransitWind = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSTransitWind.Limits = [0 Inf];
            app.AHTSTransitWind.ValueChangedFcn = createCallbackFcn(app, @AHTSTransitWindValueChanged, true);
            app.AHTSTransitWind.Value = 20;
            app.TransitWaveHeightLimitmEditField_2Label = uilabel(app.AHTSVesselPanel);
            app.TransitWaveHeightLimitmEditField_2Label.Text = 'Transit Wave Height Limit (m)';
            app.AHTSTransitWave = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSTransitWave.Limits = [0 Inf];
            app.AHTSTransitWave.ValueChangedFcn = createCallbackFcn(app, @AHTSTransitWaveValueChanged, true);
            app.AHTSTransitWave.Value = 4.5;
            app.MooringInstallWindSpeedLimitmsEditFieldLabel = uilabel(app.AHTSVesselPanel);
            app.MooringInstallWindSpeedLimitmsEditFieldLabel.Text = 'Mooring Install Wind Speed Limit (m/s)';
            app.AHTSMooringWind = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSMooringWind.Limits = [0 Inf];
            app.AHTSMooringWind.ValueChangedFcn = createCallbackFcn(app, @AHTSMooringWindValueChanged, true);
            app.AHTSMooringWind.Value = 15;
            app.CapacityfullmooringsystemsLabel = uilabel(app.AHTSVesselPanel);
            app.CapacityfullmooringsystemsLabel.Text = 'Storage Capacity (# moorings)';
            app.AHTSStorage = uieditfield(app.AHTSVesselPanel, 'numeric');
            app.AHTSStorage.Limits = [0 Inf];
            app.AHTSStorage.Value = 15;
            app.WeatherLabel1_2 = uilabel(app.Vessels);
            app.WeatherLabel1_2.Text = 'Vessel Data';
            app.LocationsLabel2_2 = uilabel(app.Vessels);
            app.LocationsLabel2_2.Text = 'Identify the following parameters of the vessels to be used for towing & installation vessels. Each vessel is assumed to work independently, so treat vessel groups as individual vessels. If there are multiple towing vessels (or vessel groups), use the spinner to select the number of the tow vessel and input the parameters. ';
            app.Image3_3 = uiimage(app.Vessels);
            app.Image3_3.ImageSource = fullfile(pathToMLAPP, 'Primary Color Logo 2026.png');
            app.ProjectDetailsTab = uitab(app.TabGroup);
            app.ProjectDetailsTab.Title = 'Project Details';
            app.NumberofMooringLinesPerTurbineLabel_2 = uilabel(app.ProjectDetailsTab);
            app.NumberofMooringLinesPerTurbineLabel_2.Text = 'Number of Mooring Lines Per Turbine';
            app.NumLines = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.NumLines.Limits = [0 Inf];
            app.NumLines.Value = 6;
            app.TurbineAssemblyTimehrLabel_3 = uilabel(app.ProjectDetailsTab);
            app.TurbineAssemblyTimehrLabel_3.Text = 'Platform Construction Time (hr)';
            app.PlatformTime = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.PlatformTime.Limits = [0 Inf];
            app.PlatformTime.Value = 336;
            app.WetStorageLabel_5 = uilabel(app.ProjectDetailsTab);
            app.WetStorageLabel_5.Text = 'Platform Construction Port Wet Storage Max';
            app.PlatformWetStorageMax = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.PlatformWetStorageMax.Limits = [0 Inf];
            app.PlatformWetStorageMax.ValueChangedFcn = createCallbackFcn(app, @PlatformWetStorageMaxValueChanged, true);
            app.PlatformWetStorageMax.Value = 25;
            app.WetStorageLabel_6 = uilabel(app.ProjectDetailsTab);
            app.WetStorageLabel_6.Text = 'Platform Construction Port Wet Storage at Start';
            app.PlatformWetStorageInit = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.PlatformWetStorageInit.Limits = [0 10];
            app.PlatformWetStorageInit.Value = 5;
            app.TurbineAssemblyTimehrLabel_4 = uilabel(app.ProjectDetailsTab);
            app.TurbineAssemblyTimehrLabel_4.Text = 'Turbine Integration Time (hr)';
            app.TurbineTime = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.TurbineTime.Limits = [0 Inf];
            app.TurbineTime.Value = 168;
            app.WetStorageLabel_7 = uilabel(app.ProjectDetailsTab);
            app.WetStorageLabel_7.Text = 'Turbine Integration Port Wet Storage Max';
            app.TurbineWetStorageMax = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.TurbineWetStorageMax.Limits = [0 Inf];
            app.TurbineWetStorageMax.ValueChangedFcn = createCallbackFcn(app, @TurbineWetStorageMaxValueChanged, true);
            app.TurbineWetStorageMax.Value = 10;
            app.WetStorageLabel_8 = uilabel(app.ProjectDetailsTab);
            app.WetStorageLabel_8.Text = 'Turbine Integration Port Wet Storage at Start';
            app.TurbineWetStorageInit = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.TurbineWetStorageInit.Limits = [0 10];
            app.TurbineWetStorageInit.ValueChangedFcn = createCallbackFcn(app, @TurbineWetStorageInitValueChanged, true);
            app.TurbineAssemblyTimehrLabel_5 = uilabel(app.ProjectDetailsTab);
            app.TurbineAssemblyTimehrLabel_5.Text = 'Platform Construction Port Hourly Rate';
            app.PlatformPortRate = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.PlatformPortRate.Limits = [0 Inf];
            app.PlatformPortRate.Value = 2700;
            app.TurbineAssemblyTimehrLabel_6 = uilabel(app.ProjectDetailsTab);
            app.TurbineAssemblyTimehrLabel_6.Text = 'Turbine Integration Port Hourly Rate';
            app.TurbinePortRate = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.TurbinePortRate.Limits = [0 Inf];
            app.TurbinePortRate.Value = 2700;
            app.TurbineAssemblyTimehrLabel_7 = uilabel(app.ProjectDetailsTab);
            app.TurbineAssemblyTimehrLabel_7.Text = 'Mooring Port Rate';
            app.MooringPortRate = uieditfield(app.ProjectDetailsTab, 'numeric');
            app.MooringPortRate.Limits = [0 Inf];
            app.MooringPortRate.Value = 2700;
            app.Image3_4 = uiimage(app.ProjectDetailsTab);
            app.Image3_4.ImageSource = fullfile(pathToMLAPP, 'Primary Color Logo 2026.png');
            app.Project = uitab(app.TabGroup);
            app.Project.Title = 'Run & Results';
            app.ResultsPlot = uiaxes(app.Project);
            title(app.ResultsPlot, 'Results')
            xlabel(app.ResultsPlot, 'Time (days)')
            ylabel(app.ResultsPlot, 'Turbines')
            zlabel(app.ResultsPlot, 'Z')
            app.ResultsSummaryLabel = uilabel(app.Project);
            app.ResultsSummaryLabel.Text = 'Results Summary';
            app.ResultsSummary = uitextarea(app.Project);
            app.ResultsSummary.Value = {'Run simulation for results'};
            app.RunSimulation = uibutton(app.Project, 'push');
            app.RunSimulation.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulation.Text = 'Run Simulation';
            app.ProjectFileNamematLabel = uilabel(app.Project);
            app.ProjectFileNamematLabel.Text = 'Project File Name (*.mat)';
            app.ExportFileName = uieditfield(app.Project, 'text');
            app.ExportFileName.ValueChangedFcn = createCallbackFcn(app, @ExportFileNameValueChanged, true);
            app.ExportFileName.Value = 'TOWTOOL_Results_GUI';
            app.ResultsTable = uitable(app.Project);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.LoadPreviousRun = uibutton(app.Project, 'push');
            app.LoadPreviousRun.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousRunButtonPushed, true);
            app.LoadPreviousRun.Text = 'Load Previous Run Results';
            app.ProjectRunDescription = uilabel(app.Project);
            app.ProjectRunDescription.Text = 'To run a new simulation with the details specified in the prior tabs, press "Run Simulation". The project will automatically be saved to the *.mat file at completion of the simulation. To view results for previous runs, enter the project file name and press "Load Previous Run Results"';
            app.Image3_5 = uiimage(app.Project);
            app.Image3_5.ImageSource = fullfile(pathToMLAPP, 'Primary Color Logo 2026.png');
            app.PostprocessingMap = uitab(app.TabGroup);
            app.PostprocessingMap.Title = 'Postprocessing - Map';
            app.ResultMapPanel = uipanel(app.PostprocessingMap);
            app.ResultMapPanel.Title = 'Panel';
            app.ExportFileNamematEditField_2Label = uilabel(app.PostprocessingMap);
            app.ExportFileNamematEditField_2Label.Text = 'Export File Name (*.mat)';
            app.ResultFileNameMap = uieditfield(app.PostprocessingMap, 'text');
            app.ResultFileNameMap.ValueChangedFcn = createCallbackFcn(app, @ResultFileNameMapValueChanged, true);
            app.ResultFileNameMap.Value = 'TOWTOOL_Results';
            app.CreateMap = uibutton(app.PostprocessingMap, 'push');
            app.CreateMap.ButtonPushedFcn = createCallbackFcn(app, @CreateMapButtonPushed, true);
            app.CreateMap.Text = 'Create Chart';
            app.AHTSCheckBox = uicheckbox(app.PostprocessingMap);
            app.AHTSCheckBox.Text = 'AHTS';
            app.Tow1CheckBox = uicheckbox(app.PostprocessingMap);
            app.Tow1CheckBox.Text = 'Tow1';
            app.IncludeinMapLabel = uilabel(app.PostprocessingMap);
            app.IncludeinMapLabel.Text = 'Include in Map';
            app.Tow2CheckBox = uicheckbox(app.PostprocessingMap);
            app.Tow2CheckBox.Text = 'Tow2';
            app.Tow3CheckBox = uicheckbox(app.PostprocessingMap);
            app.Tow3CheckBox.Text = 'Tow3';
            app.Tow4CheckBox = uicheckbox(app.PostprocessingMap);
            app.Tow4CheckBox.Text = 'Tow4';
            app.Tow5CheckBox = uicheckbox(app.PostprocessingMap);
            app.Tow5CheckBox.Enable = 'off';
            app.Tow5CheckBox.Text = 'Tow5';
            app.Image3_6 = uiimage(app.PostprocessingMap);
            app.Image3_6.ImageSource = fullfile(pathToMLAPP, 'Primary Color Logo 2026.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TOWTOOL_GUI_82

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end